classdef CCPStackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        CCPTopography               matlab.ui.control.UIAxes
        CCPImageMap                 matlab.ui.control.UIAxes
        OKButton                    matlab.ui.control.Button
        CancelGUI                   matlab.ui.control.Button
        CCPcalculatePanel           matlab.ui.container.Panel
        RunButtonCCP                matlab.ui.control.Button
        SmoothingEditField          matlab.ui.control.NumericEditField
        SmoothingkmLabel            matlab.ui.control.Label
        GridPanel                   matlab.ui.container.Panel
        PlotparametersPanel         matlab.ui.container.Panel
        PickMohoButton              matlab.ui.control.Button
        SelectPointsButton          matlab.ui.control.Button
        SaveFigureButton            matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        Y2EditField                 matlab.ui.control.NumericEditField
        Y2Label                     matlab.ui.control.Label
        X2EditField                 matlab.ui.control.NumericEditField
        X2Label                     matlab.ui.control.Label
        Y1EditField                 matlab.ui.control.NumericEditField
        Y1Label                     matlab.ui.control.Label
        X1EditField                 matlab.ui.control.NumericEditField
        X1Label                     matlab.ui.control.Label
        CreategridPanel             matlab.ui.container.Panel
        VelocityModelDropDown       matlab.ui.control.DropDown
        VelocityModelDropDownLabel  matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        dzkmEditField               matlab.ui.control.NumericEditField
        dzLabel                     matlab.ui.control.Label
        zmaxkmEditField             matlab.ui.control.NumericEditField
        zmaxkmEditFieldLabel        matlab.ui.control.Label
        dykmEditField               matlab.ui.control.NumericEditField
        dyLabel                     matlab.ui.control.Label
        dxEditField                 matlab.ui.control.NumericEditField
        dxkmLabel                   matlab.ui.control.Label
        StatusEditField             matlab.ui.control.EditField
        StatusEditFieldLabel        matlab.ui.control.Label
        CCPStackingPanelLabel       matlab.ui.control.Label
    end

    properties (Access = public)
        mainApp
        DataStruct          % 用于存放地震数据
        ccpResult       % 存放叠加后的结果
        gridStruct
        plotFlag=false
        cmap
        % 新增属性用于存储当前绘制的剖面数据
        currentProfileDistAll       % 存储 PlotButtonPushed 或 SelectpointsButtonPushed 生成的 distAll
        currentProfileDepthAll      % 存储 PlotButtonPushed 或 SelectpointsButtonPushed 生成的 depthAll
        currentProfileVAll          % 存储 PlotButtonPushed 或 SelectpointsButtonPushed 生成的 VAll (即Vpro或VAll)
        currentProfilePoints        % 存储 SelectpointsButtonPushed 交互拾取定义的原始 (X,Y) 剖面点

        currentStraightProfile_xi   % 直线剖面X坐标
        currentStraightProfile_yi   % 直线剖面Y坐标
        currentProfile_xp_all       % 交互剖面X坐标
        currentProfile_yp_all       % 交互剖面Y坐标
        Fi_current                  % 插值函数
    end
    
    methods (Access = private)
        
        function plotStationsInApp(app, gridStruct,plotFlag)
            % 清空 Panel 并删除所有子对象
            delete(allchild(app.GridPanel));
            
            % 提取台站坐标
%             stationX = gridStruct.rxInOriginalCoord(:, 1);
%             stationY = gridStruct.ryInOriginalCoord(:, 2);
%             stationX = gridStruct.stationX;
%             stationY = gridStruct.stationY;
            stationX = gridStruct.rx(:, 1);
            stationY = gridStruct.ry(:, 2);     
            % 提取网格点坐标
            XX = gridStruct.X;
            YY = gridStruct.Y;
%              XX = gridStruct.XInOriginalCoord;
%              YY = gridStruct.YInOriginalCoord;
            
            %------------------- 2. 清空 Panel 并创建坐标轴 -------------------%
            gax = axes('Parent', app.GridPanel, ...
                'Units', 'normalized', ...
                'Position', [0.05 0.12 0.95 0.80]); % 调整坐标轴位置，留出边距
            hold(gax, 'on'); % 统一使用坐标轴句柄
            
            %------------------- 3. 绘制台站、网格点和投影点 -------------------%
            % 绘制台站（红色三角，较大尺寸）
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'LineWidth', 1.5, ...
                'MarkerEdgeColor', 'r', ...
                'DisplayName', '台站位置');
            
            % 绘制网格点（灰色点，适当缩小尺寸）
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', '网格点');
            
            %------------------- 4. 添加标签和图例 -------------------%
            xlabel(gax, '东向距离 (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(gax, '北向距离 (km)', 'FontSize', 12, 'FontWeight', 'bold');
            title(gax, '台站与网格分布图', 'FontSize', 14, 'FontWeight', 'bold');
            
            % 添加图例
            % legend(gax, 'show', 'Location', 'best', 'FontSize', 10);
            
            % 启用网格线
            box(gax,"on");
            grid(gax, 'on');
            grid(gax, 'minor'); % 添加次要网格线
            
            % 调整坐标轴比例，确保等比例显示
            axis(gax, 'equal');
            
            % 设置坐标轴范围，留出一定的边距
            xMargin = 0.1 * (max(XX(:)) - min(XX(:))); % 10% 的边距
            yMargin = 0.1 * (max(YY(:)) - min(YY(:))); % 10% 的边距
            xlim(gax, [min(XX(:)) - xMargin, max(XX(:)) + xMargin]);
            ylim(gax, [min(YY(:)) - yMargin, max(YY(:)) + yMargin]);
            
            % 设置坐标轴字体大小
            set(gax, 'FontSize', 10);
            
            % 设置背景颜色为白色
            set(gax, 'Color', 'w');
            set(app.GridPanel, 'BackgroundColor', 'w');

            if plotFlag == 1 
                coord1 = [app.X1EditField.Value,app.Y1EditField.Value];
                coord2 = [app.X2EditField.Value,app.Y2EditField.Value];
    
                plot(gax,[coord1(1),coord2(1)],[coord1(2),coord2(2)],'b-o','LineWidth',2)
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');  % 红色填充圆点
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');

                text(gax,coord1(1),coord1(2),'A','FontSize',20,'HorizontalAlignment','right')
                text(gax,coord2(1),coord2(2),'B','FontSize',20,'HorizontalAlignment','left')
            end

            grid(gax, 'on');
            hold(gax, 'off');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'CCPStacking App';
            app.cmap = load(fullfile(pwd,'visualization/colormap/roma.mat'));
        end

        % Callback function
        function CancelButtonPushed(app, event)
            answer = questdlg('是否退出？', '', 'YES', 'NO', 'NO');
        
            % 根据用户选择执行操作
            switch answer
                case 'YES'
                    delete(app.UIFigure); % 删除主窗口以退出
                case 'NO'
                    return; % 取消关闭操作
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, '尚未读取数据！', '错误','Icon','error');
                return;
            end

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP叠加', ...
                    'Message','创建网格及速度模型中，请稍候...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            % create grid
            app.gridStruct = createGrid(app.DataStruct, app.dxEditField.Value, app.dykmEditField.Value, app.dzkmEditField.Value, app.zmaxkmEditField.Value);

            % velocity model
            ModelType = app.VelocityModelDropDown.Value;

            if strcmp(ModelType,'Default')
                ModelType = '3D';
            end

            npts = 5;
            app.gridStruct = getVelocityModel(ModelType,app.gridStruct,npts);
%             if strcmp(ModelType,'3D')
%                 depindx = find(app.gridStruct.z == 50);          
%                 Vs = app.gridStruct.VS(:,:,depindx);
%             end

            plotStationsInApp(app,app.gridStruct,app.plotFlag);
            app.StatusEditField.Value = '网格创建完成';

            close(msg);


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % 加载 DEM 数据，确保 Qaidam_DEM.mat 文件在 MATLAB 路径中
%             dem = load('Qaidam_DEM.mat'); % 假设 DEM 文件包含 demLon, demLat, demZ
            dem = load('SichuanLongmenshan.mat'); 
            smoothLength = fix(app.SmoothingEditField.Value/app.dxEditField.Value) + 1;
        
            if rem(smoothLength,2)==0
                smoothLength = smoothLength +1;
            end
        
            app.plotFlag = true;
            plotStationsInApp(app,app.gridStruct,app.plotFlag);
        
            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP结果', ...
                    'Message','结果展示，请稍候...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            
            X = app.ccpResult.X;
            Y = app.ccpResult.Y;
            Z = app.ccpResult.Z;
            [~,~,nz] = size(X);
            dimg_smooth = [];

            if smoothLength>1
                for n = 1:size(app.ccpResult.img,4)
                    dimg_smooth(:,:,:,n) = smooth3(squeeze(app.ccpResult.img(:,:,:,n)),'box',smoothLength);
                end

                V_smooth = mean(dimg_smooth,4);
            else
                V_smooth = mean(app.ccpResult.img,4);
            end

            % plot
            Fi = scatteredInterpolant(X(:),Y(:),Z(:),V_smooth(:));
            app.Fi_current = Fi;
        
            x1 = app.X1EditField.Value;
            x2 = app.X2EditField.Value;
            y1 = app.Y1EditField.Value;
            y2 = app.Y2EditField.Value;
        
            depth = squeeze(Z(1,1,:));
        
            % 统一采样点数量，确保地表起伏和剖面图有相同的横向分辨率
            num_profile_points = 100; % 可以根据需要调整，增加平滑度
            xi = linspace(x1,x2,num_profile_points);
            yi = linspace(y1,y2,num_profile_points);
        
            xdist = linspace(0,sqrt((x1-x2)^2 + (y1-y2)^2),num_profile_points);
        
            xpro = repmat(xi,nz,1);
            ypro = repmat(yi,nz,1);
            zpro = repmat(depth,1,num_profile_points);
        
            Vpro = Fi(xpro,ypro,zpro);
        
            % 存储当前直线剖面数据到 app 属性
            app.currentProfileDistAll = xdist; % 注意这里是直线剖面的xdist
            app.currentProfileDepthAll = depth; % imagesc的y轴，也就是深度向量
            app.currentProfileVAll = Vpro;      % 图像数据
            % 保存直线剖面坐标点
            app.currentStraightProfile_xi = xi;  % 添加这行
            app.currentStraightProfile_yi = yi;  % 添加这行
            %% --- 绘制地表起伏图 ---
            cla(app.CCPTopography,"reset"); % 清空地表起伏 Axes
        
            % 将剖面点的投影坐标 (xi, yi) 转换为经纬度
            % 确保 ProjectedCoordsTolatlon 函数可用且正确实现
            [lonp, latp] = ProjectedCoordsTolatlon(xi', yi', app.gridStruct); 
            
            % 从 DEM 中插值获取地表高程 (转换为 km)
            % interp2(X,Y,V,XI,YI)
            elevp = interp2(dem.demLon, dem.demLat, dem.demZ, lonp, latp) / 1000.;
        
            plot(app.CCPTopography, xdist, elevp, 'k', 'linewidth', 2);
            % 填充地表下方区域，使地表起伏更直观
            patch(app.CCPTopography, [xdist(1); xdist'; xdist(end)], [0; elevp; 0], [0.8 0.8 0.8], 'EdgeColor', 'none');
            
            ylabel(app.CCPTopography, 'Elev. (km)');
            % 设置地表起伏图的X轴范围与剖面图一致，但Y轴范围根据实际高程调整
            xlim(app.CCPTopography, [0, max(xdist)]);
            % 自动调整Y轴范围以适应地表高程，并留出一些裕量
            min_elev = floor(min(elevp) - 0.5); 
            max_elev = ceil(max(elevp) + 0.5);
            ylim(app.CCPTopography, [min_elev, max_elev]);
            app.CCPTopography.XTickLabel = []; % 移除X轴标签，因为下面的剖面图会显示
        
            %% --- 绘制结构剖面图 ---
            cla(app.CCPImageMap,"reset");  % 清空并重置坐标轴
            imagesc(app.CCPImageMap, xdist, depth, Vpro); % 绘制图像
            
            % 设置坐标轴属性
            axis(app.CCPImageMap, 'ij'); % 倒置Y轴，使深度向下
            xlabel(app.CCPImageMap, 'Distance (km)');
            ylabel(app.CCPImageMap, 'Depth (km)');
            xlim(app.CCPImageMap, [0, max(xdist)]);
            ylim(app.CCPImageMap, [0, 100]); % 深度范围
        
            % 添加A和B标签
            text(app.CCPImageMap,0, max(depth)*0.05, 'A', 'FontSize', 14, 'VerticalAlignment', 'bottom');
            text(app.CCPImageMap,max(xdist), max(depth)*0.05, 'B', 'FontSize', 14, 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
        
            % 设置颜色范围
            cmax = rms(Vpro(:)); 
            caxis(app.CCPImageMap, [-cmax, cmax]); 
            colormap(app.CCPImageMap,flipud(app.cmap.roma));
            colorbar(app.CCPImageMap);
        
            %% --- 关键：链接和对齐两个 Axes ---
            % 1. 链接两个 Axes 的 X 轴，确保它们在 X 方向上同步
            linkaxes([app.CCPTopography, app.CCPImageMap], 'x');
        
            % 2. 调整 Axes 的位置以确保绘图区域严格对齐
            % 设置 ActivePositionProperty 为 'position'，这样对 'Position' 的修改会影响绘图区域
            app.CCPTopography.ActivePositionProperty = 'position';
            app.CCPImageMap.ActivePositionProperty = 'position';
        
            % 获取地表起伏图的绘图区域位置
            pos_topo = get(app.CCPTopography, 'Position');
        
            % 设置结构剖面图的绘图区域位置，使其与地表起伏图的宽度和左边界相同
            pos_image = get(app.CCPImageMap, 'Position');
            pos_image(1) = pos_topo(1); % 左边界对齐
            pos_image(3) = pos_topo(3); % 宽度一致
            set(app.CCPImageMap, 'Position', pos_image);
            
            app.StatusEditField.Value = 'CCP图像展示';
            close(msg);
            uialert(app.UIFigure, 'CCP图像绘制完成！', '提示');
        end

        % Callback function
        function SaveFigureButtonPushed(app, event)
            [filename, path, index] = uiputfile({...
                '*.png','PNG file'; ...
                '*.jpg','JPG file'}, '请选择保存的文件名');
            
            if isequal(filename, 0)
                disp('取消保存');
                return;
            end
                
            % 创建临时不可见图窗
            f = figure('Visible', 'off', 'Position', [100, 100, 1000, 500]);
            
            % 设置高度比例 (1:2)
            totalHeight = 1;
            topographyHeight = totalHeight / 3;
            ccpHeight = totalHeight * 2 / 3;
            
            % 创建地形图轴
            ax1 = axes('Parent', f, 'Position', [0.1, ccpHeight + 0.05, 0.8, topographyHeight]);
            
            % 复制地形图内容
            copyobj(app.CCPTopography.Children, ax1);
            
            % 设置地形图属性
            title(ax1, 'Topography');
            ylabel(ax1, 'Elev. (km)');
            ax1.XTickLabel = [];
            grid(ax1, 'on');
            xlim(ax1, app.CCPTopography.XLim);
            ylim(ax1, app.CCPTopography.YLim);
            
            % 创建CCP图像轴
            ax2 = axes('Parent', f, 'Position', [0.1, 0.05, 0.8, ccpHeight]);
            
            % 复制CCP图像内容
            copyobj(app.CCPImageMap.Children, ax2);
            
            % 设置CCP图像属性
            title(ax2, 'CCP Image');
            xlabel(ax2, 'Distance (km)');
            ylabel(ax2, 'Depth (km)');
            axis(ax2, 'ij');
            grid(ax2, 'on');
            colormap(ax2, flipud(app.cmap.roma));
            caxis(ax2, app.CCPImageMap.CLim);
            xlim(ax2, app.CCPImageMap.XLim);
            ylim(ax2, app.CCPImageMap.YLim);
            
            % 添加颜色条
            cbar = colorbar(ax2, 'Location', 'eastoutside');
            cbar.Position(1) = 0.91; % 调整位置
            
            % 设置输出格式
            formats = {'-dpng', '-djpeg'};
            format = formats{index};
            
            % 保存图像
            try
                print(f, fullfile(path, filename), format, '-r150');
                app.StatusEditField.Value = sprintf('图像已保存: %s', filename);
                uialert(app.UIFigure, '图像保存成功!', '成功');
            catch ME
                app.StatusEditField.Value = '保存失败';
                uialert(app.UIFigure, sprintf('保存图像时出错:\n%s', ME.message), '错误');
            end
            
            % 关闭临时图窗
            close(f);
        end

        % Button pushed function: CancelGUI
        function CancelGUIPushed(app, event)
            answer = questdlg('是否退出？', '', 'YES', 'NO', 'NO');
        
            % 根据用户选择执行操作
            switch answer
                case 'YES'
                    delete(app.UIFigure); % 删除主窗口以退出
                case 'NO'
                    return; % 取消关闭操作
            end
        end

        % Button pushed function: RunButtonCCP
        function RunButtonCCPPushed(app, event)
            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP结果', ...
                    'Message','CCP计算中，请稍候...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');

            eventList = getEvents(app.DataStruct);
            eventid = {eventList.evid};

            dimg = [];    % 存储最CCP结果
            count = [];
            nMigratedEvents = 1;   % 用于计数成功完成成像的事件数
            
            % 遍历所有符合筛选条件的事件
            for iEvent = 1:length(eventid)
                evid = eventid{iEvent}; 
                % 提取当前事件对应的地震记录子集（Common Event Gather）
                gather = getCommonEventGather(app.DataStruct, evid);
                % 如果该事件的有效台站数小于 50，则跳过，以保证成像质量
                if length(gather) < 50
                    continue
                end

                msg.Value = (iEvent-1)/length(eventid);
                msg.Message = sprintf('正在处理：第 (%d/%d) 个地震', iEvent, length(eventid));
                drawnow;  % 强制界面刷新
            
                % 调用 CCPCommonEventGather 进行共转换点叠加成像
                Result = CCPCommonEventGather(gather, app.gridStruct);
                dimg(:,:,:,nMigratedEvents) = Result.img;
                count(:,:,:,nMigratedEvents) = Result.count;
                
                nMigratedEvents = nMigratedEvents + 1;
            end

            app.ccpResult.img = dimg;
            app.ccpResult.X = Result.X;
            app.ccpResult.Y = Result.Y;
            app.ccpResult.Z = Result.Z;

            msg.Value = 1;
            msg.Message = '所有数据处理完成！';
            drawnow;

            close(msg);
            uialert(app.UIFigure, 'CCP计算完成！', '提示');
            app.StatusEditField.Value = 'CCP处理完成';
        end

        % Button pushed function: OKButton, SaveFigureButton
        function OKButtonPushed(app, event)
            % 将其赋值给主 App
            app.mainApp.ccpStruct = app.ccpResult;
            app.mainApp.CCPStackingStatus = 'OK';
            % 关闭窗口
            delete(app.UIFigure);
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)
%             dem = load('Qaidam_DEM.mat');
            dem = load('SichuanLongmenshan.mat'); 
            X = app.ccpResult.X;
            Y = app.ccpResult.Y;
            Z = app.ccpResult.Z;
        
            [~,~,nz] = size(X);
            dimg_smooth = [];
            smoothLength = fix(app.SmoothingEditField.Value/app.dxEditField.Value) + 1;
        
            if rem(smoothLength,2)==0
                smoothLength = smoothLength +1;
            end

            if smoothLength>1
                for n = 1:size(app.ccpResult.img,4)
                    dimg_smooth(:,:,:,n) = smooth3(squeeze(app.ccpResult.img(:,:,:,n)),'box',smoothLength);
                end

                V_smooth = mean(dimg_smooth,4);
            else
                V_smooth = mean(app.ccpResult.img,4);
            end
            
            F = scatteredInterpolant(X(:),Y(:),Z(:),V_smooth(:));
            app.Fi_current = F;
            depth0 = 0:0.5:max(Z(:)); % 定义深度范围
        
            % 选择剖面
            f_ginput = figure; % 使用一个新的figure进行ginput，避免干扰GUI主界面
            set(f_ginput,'Position',[50 50 800 800],'Color','w')
            % 绘制三维切片图
            h = slice(X,Y,Z,V_smooth,90,90,40);
            xlabel('X (km)');
            ylabel('Y (km)');
            zlabel('Z (km)');
            set(h(:),'EdgeColor','none')
            set(gca,'ZDir','reverse')
            colormap(flipud(app.cmap.roma));
            cmax = rms(V_smooth(:));
            caxis([-cmax cmax]);
            view(0,90)
            axis equal
            title('请在地图上点击选择剖面点，双击或按Enter结束');
        
            % 通过鼠标点击选择剖面点，允许选择多个点，双击或按回车结束
            [xpoints,ypoints] = ginput;
            close(f_ginput); % 关闭ginput使用的临时figure
        
            if isempty(xpoints)
                uialert(app.UIFigure, '未选择任何剖面点。', '提示', 'Icon', 'warning');
                app.StatusEditField.Value = '取消剖面选择';
                return;
            end
        
            % 存储原始的交互拾取点
            app.currentProfilePoints = [xpoints, ypoints];

            % 生成剖面
            profileAll={};
            for n = 1:length(xpoints)-1
                profile=[xpoints(n),ypoints(n);xpoints(n+1),ypoints(n+1)];
                profileAll{n} = profile;
            end
        
            % 初始化变量
            VAll=[];
            distAll = [];
            depthAll = [];
            totalDist = 0;
            elevAll = [];
            app.currentProfile_xp_all = []; % 初始化
            app.currentProfile_yp_all = []; % 初始化
            % 遍历每个剖面
            for n = 1:length(profileAll)
                x1 = profileAll{n}(1,1);
                y1 = profileAll{n}(1,2);
                x2 = profileAll{n}(2,1);
                y2 = profileAll{n}(2,2);
                l = (y2-y1)/(x2-x1);
                npoints = 100;
                xp = linspace(x1,x2,npoints)';
                yp = y1+(xp-x1)*l;
                dist0 = sqrt((xp-x1).^2 + (yp-y1).^2);
                % 绘制RFs的几何形状
                Y_profile = repmat(yp',length(depth0),1);
                X_profile = repmat(xp',length(depth0),1);
                Z_profile = repmat(depth0',1,length(xp));
                DIST_profile = repmat(dist0',length(depth0),1);
                Vprofile = F(X_profile,Y_profile,Z_profile);
                Vprofile(isnan(Vprofile)) = NaN;
        
                if n > 1
                    totalDist = max(distAll(:));
                end
                distAll = [distAll, DIST_profile+totalDist];
                depthAll = [depthAll,Z_profile];
                VAll = [VAll, Vprofile];
                % 提取DEM文件中的地表高程
                [lonp, latp] = ProjectedCoordsTolatlon(xp, yp, app.gridStruct);
                elevp = interp2(dem.demLon,dem.demLat,dem.demZ,lonp,latp)/1000.;
                elevAll = [elevAll; elevp];
                app.currentProfile_xp_all = [app.currentProfile_xp_all; xp];
                app.currentProfile_yp_all = [app.currentProfile_yp_all; yp];
            end
            distElev = distAll(1,:)';

            % --- 在生成 distAll, depthAll, VAll 之后，存储它们到 app 属性 ---
            app.currentProfileDistAll = distAll;
            app.currentProfileDepthAll = depthAll;
            app.currentProfileVAll = VAll;   
            
            % --- 绘制地表起伏图 ---
            cla(app.CCPTopography,"reset");
            plot(app.CCPTopography,distElev,elevAll,'k','linewidth',2);
            patch(app.CCPTopography,[distElev(1);distElev;distElev(end)],[0;elevAll;0],[0.8 0.8 0.8]);
            ylabel(app.CCPTopography,'Elev. (km)');
            % 设置地表起伏图的X轴范围
            xlim(app.CCPTopography, [0 max(distAll(1,:))]);
            ylim(app.CCPTopography, [0 6]); % 保持Y轴范围不变
            app.CCPTopography.XTickLabel = []; % 移除X轴标签，因为下面的剖面图会显示X轴
        
            % --- 绘制结构剖面图 ---
            cla(app.CCPImageMap,"reset");
            h = pcolor(app.CCPImageMap,distAll,depthAll,VAll);
            % 设置结构剖面图的X轴范围
            xlim(app.CCPImageMap, [0 max(distAll(1,:))]);
            ylim(app.CCPImageMap, [0 100]); % 保持Y轴范围不变
            colormap(app.CCPImageMap,flipud(app.cmap.roma));
            cmax = rms(VAll(:));
            caxis(app.CCPImageMap, [-cmax, cmax]);
            set(h,'EdgeColor','none');
            axis(app.CCPImageMap,'ij'); % 倒置Y轴

            % 设置坐标轴属性
            xlabel(app.CCPImageMap,'Distance (km)');
            ylabel(app.CCPImageMap,'Depth (km)');
            text(app.CCPImageMap,0,-5,'A','FontSize',14);
            text(app.CCPImageMap,max(distAll(1,:))-5,-5,'B','FontSize',14);

            % --- 关键改进：链接两个 Axes 的 X 轴 ---
            % 这将确保两个绘图的 X 轴范围和缩放行为始终保持一致
            linkaxes([app.CCPTopography, app.CCPImageMap], 'x');

            % --- 进一步调整 Axes 位置以确保绘图区域严格对齐 ---
            % 这段代码确保绘图区域（不包括标签和标题）的宽度和左边界是相同的
            % 设置 ActivePositionProperty 为 'position'，这样对 'Position' 的修改会影响绘图区域
            app.CCPTopography.ActivePositionProperty = 'position';
            app.CCPImageMap.ActivePositionProperty = 'position';

            % 获取地表起伏图的绘图区域位置
            pos_topo = get(app.CCPTopography, 'Position');

            % 设置结构剖面图的绘图区域位置，使其与地表起伏图的宽度和左边界相同
            pos_image = get(app.CCPImageMap, 'Position');
            pos_image(1) = pos_topo(1); % 左边界对齐
            pos_image(3) = pos_topo(3); % 宽度一致
            set(app.CCPImageMap, 'Position', pos_image);

            app.StatusEditField.Value = 'CCP图像展示';
            uialert(app.UIFigure, 'CCP图像绘制完成！', '提示');
        end

        % Button pushed function: PickMohoButton
        function PickMohoButtonPushed(app, event)
            % 检查必要数据是否存在
            if isempty(app.ccpResult) || isempty(app.currentProfileDistAll)
                uialert(app.UIFigure, '请先运行CCP计算并绘制剖面！', '警告');
                return;
            end

            % 1. 创建临时拾取窗口
            h_pick_fig = figure('Name', '拾取莫霍面', 'NumberTitle','off', ...
                'Position', [100 100 800 600], 'Color', 'w');
            ax = axes('Parent', h_pick_fig, 'Position', [0.1 0.1 0.8 0.8]);

            % 复制当前剖面图
            dist_vec = app.currentProfileDistAll(1,:);
            depth_vec = app.currentProfileDepthAll(:,1);
            imagesc(ax, dist_vec, depth_vec, app.currentProfileVAll);
            colormap(ax, flipud(app.cmap.roma));
            axis(ax, 'ij');
            title(ax, '点击选择莫霍面位置（多点），按Enter结束');
            xlabel(ax, '距离 (km)');
            ylabel(ax, '深度 (km)');
            caxis(ax, [-0.01, 0.01]); % 设置颜色范围

            % 2. 交互式拾取
            [x_dists, depths] = ginput();
            close(h_pick_fig);

            if isempty(x_dists)
                uialert(app.UIFigure, '未选择任何点', '提示');
                return;
            end

            % 3. 准备存储结果
            results = struct(...
                'ProfileDistance', num2cell(x_dists), ...
                'ClickedDepth', num2cell(depths), ...
                'OriginalX', [], ...
                'OriginalY', [], ...
                'MohoDepth', [], ...
                'Longitude', [], ...
                'Latitude', []);

            % 4. 处理每个拾取点
            hold(app.CCPImageMap, 'on');

            % 创建图例句柄
            h_click = plot(app.CCPImageMap, NaN, NaN, 'go', 'MarkerSize', 10, 'DisplayName', '点击深度');
            h_moho = plot(app.CCPImageMap, NaN, NaN, 'r*', 'MarkerSize', 15, 'DisplayName', '莫霍面深度');

            for i = 1:length(x_dists)
                % 获取当前距离和深度
                current_dist = x_dists(i);
                clicked_depth = depths(i);

                % 在距离向量中找到最近的点
                [~, dist_idx] = min(abs(dist_vec - current_dist));

                % 在深度向量中找到搜索范围
                search_min = max(1, find(depth_vec >= (clicked_depth - 5), 1));
                search_max = min(length(depth_vec), find(depth_vec <= (clicked_depth + 5), 1, 'last'));

        % 提取搜索范围内的数据
        search_depths = depth_vec(search_min:search_max);
        search_values = app.currentProfileVAll(search_min:search_max, dist_idx);
        
        % 找到最大振幅绝对值及其深度
        [~, max_idx] = max(search_values);
        moho_depth = search_depths(max_idx);
        
        % 反推原始坐标 - 使用最近邻方法
        if ~isempty(app.currentStraightProfile_xi)
            % 直线剖面 - 使用最近距离点
            [~, idx] = min(abs(dist_vec - current_dist));
            orig_X = app.currentStraightProfile_xi(idx);
            orig_Y = app.currentStraightProfile_yi(idx);
        elseif ~isempty(app.currentProfile_xp_all)
            % 交互剖面 - 使用累积距离找到最近点
            xp_all = app.currentProfile_xp_all;
            yp_all = app.currentProfile_yp_all;
            
            % 计算累积距离
            segments = sqrt(diff(xp_all).^2 + diff(yp_all).^2);
            cum_dist = [0; cumsum(segments)];
            
            % 找到最接近的累积距离点
            [~, idx] = min(abs(cum_dist - current_dist));
            orig_X = xp_all(idx);
            orig_Y = yp_all(idx);
        else
            uialert(app.UIFigure, '无法确定坐标映射', '错误');
            return;
        end
        
        % 转换为经纬度
        [lon, lat] = ProjectedCoordsTolatlon(orig_X, orig_Y, app.gridStruct);
        
        % 存储结果
        results(i).OriginalX = orig_X;
        results(i).OriginalY = orig_Y;
        results(i).MohoDepth = moho_depth;
        results(i).Longitude = lon;
        results(i).Latitude = lat;
        
        % 在GUI中标记结果
        plot(app.CCPImageMap, current_dist, clicked_depth, 'go', 'MarkerSize', 10);
        plot(app.CCPImageMap, current_dist, moho_depth, 'r*', 'MarkerSize', 15);
        
        % 添加文本标签
        text(app.CCPImageMap, current_dist+5, moho_depth, ...
            sprintf('%.1f km', moho_depth), ...
            'Color', 'r', 'FontSize', 10);
    end
    
    hold(app.CCPImageMap, 'off');
    
    % 添加图例
    legend(app.CCPImageMap, [h_click, h_moho], 'Location', 'best');
    
    % 5. 保存结果到文本文件
    try
        [filename, pathname] = uiputfile('*.txt', '保存莫霍面拾取结果');
        if filename == 0
            app.StatusEditField.Value = '用户取消保存';
            return;
        end
        
        fullpath = fullfile(pathname, filename);
        fid = fopen(fullpath, 'w');
        
        % 写入文件头
        fprintf(fid, '%-15s %-15s %-15s %-15s %-15s %-15s %-15s\n', ...
            'ProfileDist(km)', 'ClickedDepth(km)', 'OriginalX(km)', 'OriginalY(km)', ...
            'MohoDepth(km)', 'Longitude(deg)', 'Latitude(deg)');
        
        % 写入数据
        for i = 1:length(results)
            fprintf(fid, '%-15.3f %-15.3f %-15.3f %-15.3f %-15.3f %-15.6f %-15.6f\n', ...
                results(i).ProfileDistance, ...
                results(i).ClickedDepth, ...
                results(i).OriginalX, ...
                results(i).OriginalY, ...
                results(i).MohoDepth, ...
                results(i).Longitude, ...
                results(i).Latitude);
        end
        
        fclose(fid);
        
        % 显示成功消息
        app.StatusEditField.Value = sprintf('莫霍面深度已保存至: %s', fullpath);
        uialert(app.UIFigure, sprintf('成功保存 %d 个拾取点数据到:\n%s', length(results), fullpath), '保存成功');
        
    catch ME
        uialert(app.UIFigure, sprintf('保存文件时出错:\n%s', ME.message), '错误');
        app.StatusEditField.Value = '文件保存失败';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CCPStackingPanelLabel = uilabel(app.UIFigure);
            app.CCPStackingPanelLabel.Text = 'CCP Stacking Panel';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.CreategridPanel = uipanel(app.UIFigure);
            app.CreategridPanel.Title = 'Create grid';
            app.dxkmLabel = uilabel(app.CreategridPanel);
            app.dxkmLabel.Text = 'dx ';
            app.dxEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dxEditField.Value = 10;
            app.dyLabel = uilabel(app.CreategridPanel);
            app.dyLabel.Text = 'dy';
            app.dykmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dykmEditField.Value = 10;
            app.zmaxkmEditFieldLabel = uilabel(app.CreategridPanel);
            app.zmaxkmEditFieldLabel.Text = 'zmax';
            app.zmaxkmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.zmaxkmEditField.Value = 100;
            app.dzLabel = uilabel(app.CreategridPanel);
            app.dzLabel.Text = 'dz';
            app.dzkmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dzkmEditField.Value = 1;
            app.CreateButton = uibutton(app.CreategridPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.VelocityModelDropDownLabel = uilabel(app.CreategridPanel);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model';
            app.VelocityModelDropDown = uidropdown(app.CreategridPanel);
            app.VelocityModelDropDown.Items = {'1D', '3D', 'Default'};
            app.VelocityModelDropDown.Value = 'Default';
            app.PlotparametersPanel = uipanel(app.UIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters:';
            app.X1Label = uilabel(app.PlotparametersPanel);
            app.X1Label.Text = 'X1';
            app.X1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y1Label = uilabel(app.PlotparametersPanel);
            app.Y1Label.Text = 'Y1';
            app.Y1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2Label = uilabel(app.PlotparametersPanel);
            app.X2Label.Text = 'X2';
            app.X2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditField.Value = 200;
            app.Y2Label = uilabel(app.PlotparametersPanel);
            app.Y2Label.Text = 'Y2';
            app.Y2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y2EditField.Value = 130;
            app.PlotButton = uibutton(app.PlotparametersPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveFigureButton = uibutton(app.PlotparametersPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.SelectPointsButton = uibutton(app.PlotparametersPanel, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Text = 'Select Points';
            app.PickMohoButton = uibutton(app.PlotparametersPanel, 'push');
            app.PickMohoButton.ButtonPushedFcn = createCallbackFcn(app, @PickMohoButtonPushed, true);
            app.PickMohoButton.Text = 'Pick Moho';
            app.GridPanel = uipanel(app.UIFigure);
            app.GridPanel.Title = 'Grid Panel';
            app.CCPcalculatePanel = uipanel(app.UIFigure);
            app.CCPcalculatePanel.Title = 'CCP calculate';
            app.SmoothingkmLabel = uilabel(app.CCPcalculatePanel);
            app.SmoothingkmLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.CCPcalculatePanel, 'numeric');
            app.SmoothingEditField.Value = 15;
            app.RunButtonCCP = uibutton(app.CCPcalculatePanel, 'push');
            app.RunButtonCCP.ButtonPushedFcn = createCallbackFcn(app, @RunButtonCCPPushed, true);
            app.RunButtonCCP.Text = 'Run';
            app.CancelGUI = uibutton(app.UIFigure, 'push');
            app.CancelGUI.ButtonPushedFcn = createCallbackFcn(app, @CancelGUIPushed, true);
            app.CancelGUI.Text = 'Cancel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.CCPImageMap = uiaxes(app.GridLayout);
            title(app.CCPImageMap, 'CCP image')
            xlabel(app.CCPImageMap, 'Distance (km)')
            ylabel(app.CCPImageMap, 'Depth (km)')
            zlabel(app.CCPImageMap, 'Z')
            app.CCPTopography = uiaxes(app.GridLayout);
            title(app.CCPTopography, 'Topography')
            ylabel(app.CCPTopography, 'Y')
            zlabel(app.CCPTopography, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCPStackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end