classdef HkStackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HkParamPanel           matlab.ui.container.Panel
        dkEditField            matlab.ui.control.NumericEditField
        dkEditFieldLabel       matlab.ui.control.Label
        dhkmEditField          matlab.ui.control.NumericEditField
        dhkmEditFieldLabel     matlab.ui.control.Label
        kmaxEditField          matlab.ui.control.NumericEditField
        kmaxEditFieldLabel     matlab.ui.control.Label
        kminEditField          matlab.ui.control.NumericEditField
        kminEditFieldLabel     matlab.ui.control.Label
        HmaxkmEditField        matlab.ui.control.NumericEditField
        HmaxkmLabel            matlab.ui.control.Label
        HminkmEditField        matlab.ui.control.NumericEditField
        HminkmLabel            matlab.ui.control.Label
        ManualpickingButton    matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        QiuitButton            matlab.ui.control.Button
        HkPanelLabel           matlab.ui.control.Label
        SaveResultsButton      matlab.ui.control.Button
        StatusLabel1           matlab.ui.control.Label
        StatusLabel            matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        ResultsTable           matlab.ui.control.Table
        SelectStationDropDown  matlab.ui.control.DropDown
        RunButton              matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Params      % 结构体，至少包含 fields: stations, DataStruct
        HKresults   % 存储 HKstacking 计算结果的结构数组
        mainApp     % 主应用引用（如果需要传递）
        DataStruct  % 可直接存储传递的数据结构
        plotData
        StopFlag = false;  % 停止计算标志，默认 false
        PauseFlag = false; % 暂停计算标志，默认 false
    end
    

    methods (Access = public)
        function loadConfigData(app,defaultParam)
            app.HminkmEditField.Value = defaultParam.hmin;
            app.HmaxkmEditField.Value = defaultParam.hmax;
            
            app.kminEditField.Value = defaultParam.kmin;
            app.kmaxEditField.Value = defaultParam.kmax;
            
            updateStationDropdown(app)
            
        end
    end

   
    methods (Access = private)
        
        function updateStationDropdown(app)
            if isempty(app.DataStruct)
                app.SelectStationDropDown.Items = {'(no stations)'};
                app.SelectStationDropDown.Value = '(no stations)';
            else
                gather = app.DataStruct;
                stationList = getStations(gather);

%                 stationList = getStation(app.DataStruct);
                stationNames = {stationList.sta};
                items = [{'None', 'All'}, stationNames];
                app.SelectStationDropDown.Items = items;
                app.SelectStationDropDown.Value = 'None';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'HkStacking App';
            if isempty(app.DataStruct)
%                 disp('DataStruct is empty123.');
            else
                disp('DataStruct received:');
                disp(app.DataStruct);
            end

            updateStationDropdown(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            Hmin = app.HminkmEditField.Value; 
            Hmax = app.HmaxkmEditField.Value; 
            dh = app.dhkmEditField.Value;

            kmin = app.kminEditField.Value;
            kmax = app.kmaxEditField.Value;
            dk = app.dkEditField.Value;
            

            % 检查用户输入的有效性
            if isnan(Hmin) || isnan(Hmax)
                uialert(app.UIFigure, 'Input Error', 'Error');
                return;
            end

            Hall = Hmin:dh:Hmax;
            kappa = kmin:dk:kmax;

            app.HKresults = [];  
            app.plotData = []; 
            app.ResultsTable.Data = {}; 
            app.StopFlag = false;  
            app.StatusLabel.Text = 'Computing...';  % 
            
            % 检查是否有数据传入
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'Load data first', 'Error');
                return;
            end

            selStation = app.SelectStationDropDown.Value;
            if strcmpi(selStation, 'All')
                try
                    app.StatusLabel.Text = 'Computing all stations!';
                    [app.HKresults, app.plotData] = HKstacking(app.DataStruct, app, Hall,kappa);
                catch ME
                    uialert(app.UIFigure, ['HKstacking error：' ME.message], 'error');
                    return;
                end

                if isempty(app.HKresults)
                    uialert(app.UIFigure, ['HKstacking error：' ME.message], 'error');
                    return;
                end
            else
                gather = getCommonStationGather(app.DataStruct, selStation);
                if isempty(gather)
                    uialert(app.UIFigure, ['Cannot get ' selStation ' data'], 'Error');
                    return;
                end
                try
%                     HKres = HKstacking(gather);
                    app.StatusLabel.Text = ['Computing station ', selStation];
                    [HKres, app.plotData] = HKstacking(gather, app, Hall,kappa);

                catch ME
                    uialert(app.UIFigure, ['HKstacking Error' ME.message], 'Error');
                    return;
                end
                app.HKresults = HKres;

            end
            if ~isempty(app.HKresults)
                nResults = numel(app.HKresults);
                data = cell(nResults, 7);  % 7列分别对应: Sta, Lon, Lat, H, Hstd, k, kstd

                % 遍历所有结果并填入 data 中
                for i = 1:nResults
                    data{i, 1} = app.HKresults(i).Sta;          % 第1列: Sta
                    data{i, 2} = sprintf('%.2f', app.HKresults(i).Lon);          % 第2列: Lon
                    data{i, 3} = sprintf('%.2f', app.HKresults(i).Lat);          % 第3列: Lat
                    data{i, 4} = sprintf('%.2f', app.HKresults(i).H);            % 第4列: H
                    data{i, 5} = sprintf('%.2f', app.HKresults(i).Hstd);         % 第5列: Hstd
                    data{i, 6} = sprintf('%.2f', app.HKresults(i).k);            % 第6列: k
                    data{i, 7} = sprintf('%.2f', app.HKresults(i).kstd);         % 第7列: kstd
                end
                % 设置 UITable 的列名
                app.ResultsTable.ColumnName = {'Sta', 'Lon', 'Lat', 'H (km)', 'Hstd (km)', 'k', 'kstd'};

                % 将 data 赋值给 UITable
                app.ResultsTable.Data = data;
                % 创建左对齐的样式并应用到整个 UITable
                leftAlignStyle = uistyle('HorizontalAlignment', 'left');
                addStyle(app.ResultsTable, leftAlignStyle);
            end
%             end

            uialert(app.UIFigure, 'Click the table to plot', 'Note');
            app.StatusLabel.Text = 'HK done!';

        end

        % Cell selection callback: ResultsTable
        function ResultsTableCellSelection(app, event)
            if ~isempty(event.Indices)
                
                row = event.Indices(1);  % 获取选择的行号

                app.StatusLabel.Text = 'Plotting';  % 更新状态栏
                drawnow;
                % 从 app.plotData 中获取对应台站的数据
                Hall = app.plotData(row).Hall;
                kappa = app.plotData(row).kappa;
                Cn = app.plotData(row).Cn;
                besth = app.plotData(row).besth;
                bestk = app.plotData(row).bestk;
                hstd = app.plotData(row).hstd;
                kstd = app.plotData(row).kstd;
                sta = app.HKresults(row).Sta;  % 获取台站名称

                % 在 UIAxes 上绘图
                cla(app.UIAxes_2);  % 清空之前的图像
                hold(app.UIAxes_2, 'on');  % 允许叠加绘图

                imagesc(app.UIAxes_2, Hall, kappa, Cn');  % 绘制密度图
                scatter(app.UIAxes_2, besth, bestk, 100, 'white', 'x');  % 绘制散点

                text(app.UIAxes_2, 0.98, 0.98, ...
                    ['Depth=', num2str(besth, '%.2f'), setstr(177), num2str(hstd, '%.2f'), ' km'], ...
                    'Units', 'normalized', 'HorizontalAlignment', 'right', ...
                    'VerticalAlignment', 'top', 'Color', 'k', 'FontSize', 8, 'BackgroundColor', 'w');
                
                text(app.UIAxes_2, 0.98, 0.90, ...
                    ['Vp/Vs=', num2str(bestk, '%.2f'), setstr(177), num2str(kstd, '%.2f')], ...
                    'Units', 'normalized', 'HorizontalAlignment', 'right', ...
                    'VerticalAlignment', 'top', 'Color', 'k', 'FontSize', 8, 'BackgroundColor', 'w');

                % 设置坐标轴和标题
                axis(app.UIAxes_2, 'xy');
                xlabel(app.UIAxes_2, 'Depth (km)', 'FontSize', 12);
                ylabel(app.UIAxes_2, 'Vp/Vs', 'FontSize', 12);
                title(app.UIAxes_2, [sta, ' HK stacking'], 'FontSize', 14);
                colormap(app.UIAxes_2, jet);  % 设置 colormap
                colorbar(app.UIAxes_2);  % 显示颜色条
                % 调整坐标轴使图充满区域
                axis(app.UIAxes_2, 'tight');
                set(app.UIAxes_2, 'LooseInset', [0 0 0 0]);

                hold(app.UIAxes_2, 'off');  % 关闭 hold

                % 下面绘制第二幅图（waveform）
                % 从 plotData 中读取第二幅图的数据
                t = app.plotData(row).t;
                seis = app.plotData(row).seis;
                dist = app.plotData(row).dist;
                rayp = app.plotData(row).rayp;
                Tdelay_Ps = app.plotData(row).Tdelay_Ps;
                Tdelay_PpPms = app.plotData(row).Tdelay_PpPms;
                Tdelay_PpSms = app.plotData(row).Tdelay_PpSms;
                vp_ave = app.plotData(row).vp_ave;
                indexh = app.plotData(row).indexh;
                indexk = app.plotData(row).indexk;
                % 在 UIAxes_2 上绘制波形图
                
                cla(app.UIAxes);
%                 axes(app.UIAxes);
                hold(app.UIAxes, 'on');
                % 循环绘制每条波形和填充颜色（参考你原来的代码）
                
                for id = length(dist):-1:1
                    x = seis(:, id) * 30;
                    shift = dist(id);
                    plot(app.UIAxes, t, x + shift, 'k', 'LineWidth', 1);

                    thre = 0.01;
                    % 填充正相位
                    upper = x;
                    upper(upper <= thre) = 0;
                    upper(1) = 0; upper(end) = 0;
                    lower = zeros(length(x),1);
                    % 注意：jbfill 函数需要支持传入 parent 参数，如果你自定义了 jbfill，请确保它支持
                    
                    jbfill_1(t(:), upper + shift, lower + shift, 'r', 'k', 0, 0.5, app.UIAxes);

                    % 填充负相位
                    upper = zeros(length(x),1);
                    lower = x;
                    lower(lower >= -thre) = 0;
                    lower(1) = 0; lower(end) = 0;
                    jbfill_1(t(:), upper + shift, lower + shift, [0.17,0.17,0.17], 'k', 0, 0.5, app.UIAxes);
                end
                % 设置轴范围和标签
                xlim(app.UIAxes, [-5, 35]);
                ylim(app.UIAxes, [20, 100]);

                % 标注主要相位（散点绘制）
                for id = length(dist):-1:1
                    shift = dist(id);
                    % p = rayp(id) / 6371; % s/km
                    % % 计算理论到时
                    % t0p1s = besth * (sqrt((bestk/vp_ave)^2 - p^2) - sqrt((1/vp_ave)^2 - p^2));
                    % t2p1s = besth * (sqrt((bestk/vp_ave)^2 - p^2) + sqrt((1/vp_ave)^2 - p^2));
                    % t1p2s = 2 * besth * sqrt((bestk/vp_ave)^2 - p^2);
                    % scatter(app.UIAxes, 0, shift, 20, 'blue', 'filled');
                    % scatter(app.UIAxes, t0p1s, shift, 20, 'black', 'filled');
                    % scatter(app.UIAxes, t2p1s, shift, 20, 'black', 'filled');
                    % scatter(app.UIAxes, t1p2s, shift, 20, 'black', 'filled');

                    % crust 1.0 model
                    t0p1s_delay = Tdelay_Ps(indexh, id, indexk);
                    t2p1s_delay = Tdelay_PpPms(indexh, id, indexk);
                    t1p2s_delay = Tdelay_PpSms(indexh, id, indexk);
                    scatter(app.UIAxes, 0, shift, 20, 'blue', 'filled');
                    scatter(app.UIAxes, t0p1s_delay, shift, 20, 'blue', 'filled');
                    scatter(app.UIAxes, t2p1s_delay, shift, 20, 'blue', 'filled');
                    scatter(app.UIAxes, t1p2s_delay, shift, 20, 'blue', 'filled');
                end

                % 设置第二幅图的标题和轴标签
                title(app.UIAxes, [sta, ' waveform'], 'FontSize', 14);
                xlabel(app.UIAxes, 'Time (sec)');
                ylabel(app.UIAxes, 'Distance (deg)');
                set(app.UIAxes, 'FontSize', 12);
                box(app.UIAxes,'on')
                hold(app.UIAxes, 'off');

            end
            app.StatusLabel.Text = 'Hk plots done';  % 更新状态栏
           
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopFlag = true;
            app.StatusLabel.Text = 'Compute stop';  % 更新状态栏显示计算停止
            uialert(app.UIFigure, 'Compute stop', 'Note');
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            % 检查是否有计算结果
            if isempty(app.HKresults)
                uialert(app.UIFigure, 'No data to save', 'Error');
                return;
            end

            % 弹出文件保存对话框
            [file, path] = uiputfile('*.txt', 'Save');
            if isequal(file, 0)
                % 用户取消保存操作
                return;
            end

            % 拼接完整的文件路径
            fullFileName = fullfile(path, file);

            % 打开文件进行写入
            fid = fopen(fullFileName, 'w');
            if fid == -1
                uialert(app.UIFigure, 'Cannot open the file', 'Error');
                return;
            end

            % 写入文件头部
            fprintf(fid, 'Sta\tLon\tLat\tH (km)\tHstd (km)\tk\tkstd\n');

            % 遍历计算结果并写入文件
            for i = 1:numel(app.HKresults)
                % 将计算结果按列写入文本文件
                fprintf(fid, '%s\t%.2f\t%.2f\t%.2f\t%.2f\t%.2f\t%.2f\n', ...
                    app.HKresults(i).Sta, ...
                    app.HKresults(i).Lon, ...
                    app.HKresults(i).Lat, ...
                    app.HKresults(i).H, ...
                    app.HKresults(i).Hstd, ...
                    app.HKresults(i).k, ...
                    app.HKresults(i).kstd);
            end

            % 关闭文件
            fclose(fid);

            % 弹出提示框，告知用户保存成功
            uialert(app.UIFigure, ['Results are saved to: ', fullFileName], 'Success');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainApp.hkStackingStatus = 'OK';
            delete(app.UIFigure);
        end

        % Button pushed function: QiuitButton
        function QiuitButtonPushed(app, event)
            answer = questdlg('Do you want to quit?', '', 'YES', 'NO', 'NO');
            switch answer
                case 'YES'
                    delete(app.UIFigure); 
                case 'NO'
                    return; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectStationDropDown = uidropdown(app.UIFigure);
            app.SelectStationDropDown.Items = {'Select Station'};
            app.SelectStationDropDown.Value = 'Select Station';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Sta'; 'Lon'; 'Lat'; 'H'; 'Hstd'; 'k'; 'kstd'};
            app.ResultsTable.RowName = {};
            app.ResultsTable.CellSelectionCallback = createCallbackFcn(app, @ResultsTableCellSelection, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            app.StatusLabel1 = uilabel(app.UIFigure);
            app.StatusLabel1.Text = 'Status：';
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.HkPanelLabel = uilabel(app.UIFigure);
            app.HkPanelLabel.Text = 'H-k Panel';
            app.QiuitButton = uibutton(app.UIFigure, 'push');
            app.QiuitButton.ButtonPushedFcn = createCallbackFcn(app, @QiuitButtonPushed, true);
            app.QiuitButton.Text = 'Qiuit';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ManualpickingButton = uibutton(app.UIFigure, 'push');
            app.ManualpickingButton.Text = 'Manual picking';
            app.HkParamPanel = uipanel(app.UIFigure);
            app.HkParamPanel.Title = 'Hk Param';
            app.HminkmLabel = uilabel(app.HkParamPanel);
            app.HminkmLabel.Text = 'Hmin (km)';
            app.HminkmEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.HminkmEditField.Value = 30;
            app.HmaxkmLabel = uilabel(app.HkParamPanel);
            app.HmaxkmLabel.Text = 'Hmax (km)';
            app.HmaxkmEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.HmaxkmEditField.Value = 70;
            app.kminEditFieldLabel = uilabel(app.HkParamPanel);
            app.kminEditFieldLabel.Text = 'k min';
            app.kminEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.kminEditField.Value = 1.6;
            app.kmaxEditFieldLabel = uilabel(app.HkParamPanel);
            app.kmaxEditFieldLabel.Text = 'k max';
            app.kmaxEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.kmaxEditField.Value = 2;
            app.dhkmEditFieldLabel = uilabel(app.HkParamPanel);
            app.dhkmEditFieldLabel.Text = 'dh (km)';
            app.dhkmEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.dhkmEditField.Value = 0.1;
            app.dkEditFieldLabel = uilabel(app.HkParamPanel);
            app.dkEditFieldLabel.Text = 'dk';
            app.dkEditField = uieditfield(app.HkParamPanel, 'numeric');
            app.dkEditField.Value = 0.01;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HkStackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end