classdef Migration3DApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImportMenu                  matlab.ui.container.Menu
        SyntheticDataMenu           matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        GridPanel                   matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        SSAParTab                   matlab.ui.container.Tab
        AlphaEditField              matlab.ui.control.NumericEditField
        AlphaEditFieldLabel         matlab.ui.control.Label
        RankEditField               matlab.ui.control.NumericEditField
        RankEditFieldLabel          matlab.ui.control.Label
        Is_SSADropDown              matlab.ui.control.DropDown
        Is_SSALabel                 matlab.ui.control.Label
        LSMParTab                   matlab.ui.container.Tab
        MuEditField                 matlab.ui.control.NumericEditField
        MuEditFieldLabel            matlab.ui.control.Label
        IterationEditField          matlab.ui.control.NumericEditField
        IterationEditField_2Label   matlab.ui.control.Label
        RunButtonLSM                matlab.ui.control.Button
        SelectEventsDropDown        matlab.ui.control.DropDown
        PlotparametersPanel         matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        Y2EditField                 matlab.ui.control.NumericEditField
        Y2EditFieldLabel            matlab.ui.control.Label
        X2EditField                 matlab.ui.control.NumericEditField
        X2EditFieldLabel            matlab.ui.control.Label
        Y1EditField                 matlab.ui.control.NumericEditField
        Y1EditFieldLabel            matlab.ui.control.Label
        X1EditField                 matlab.ui.control.NumericEditField
        X1EditFieldLabel            matlab.ui.control.Label
        CreateGridPanel             matlab.ui.container.Panel
        dxkmEditField               matlab.ui.control.NumericEditField
        dxLabel                     matlab.ui.control.Label
        dzkmEditField               matlab.ui.control.NumericEditField
        dzkmEditFieldLabel          matlab.ui.control.Label
        dykmEditField               matlab.ui.control.NumericEditField
        dykmEditFieldLabel          matlab.ui.control.Label
        VelocityModelDropDown       matlab.ui.control.DropDown
        VelocityModelDropDownLabel  matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        QuitButton                  matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        Migration3DPanelLabel       matlab.ui.control.Label
        UIAxes_lsm                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    %% user define properties
    properties (Access = public)
        mainApp             % 用于存储主应用的引用
        MigrationParam
        DataStruct
        gridStruct
        migResult
        
    end
    
    properties (Access = private)
        selectedEvid     % 存放选取的事件序号
        cmap

    end


    %% user define methods
    methods (Access = public)
        function loadConfigData(app, defaultParam)
            % 1) 把输入的 param 暂存到 app 的属性
            app.MigrationParam = defaultParam;                              % 无网格和速度模型信息

            % 2) 将 inputConfig 各字段值写到对应的组件：
            if defaultParam.is_ssa == 1
                app.Is_SSADropDown.Value='YES';
            elseif defaultParam.is_ssa == 0
                app.Is_SSADropDown.Value='NO';
            end

            app.RankEditField.Value = defaultParam.rank_p;
            app.AlphaEditField.Value = defaultParam.alpha;

            % 同时更新事件列表
            updateEventDropdown(app)
        end

    end


    methods (Access = private)
        function updateEventDropdown(app)

            if isempty(app.DataStruct)
                app.SelectEventsDropDown.Items = {'(no events)'};
                app.SelectEventsDropDown.Value = '(no events)';
                return;
            end

            eList = getEvents(app.DataStruct);
            eIDs  = {eList.evid};
            % 在这里也可以加一个 "All" 选项:
            items = [{'Select events','All'}, eIDs];

            app.SelectEventsDropDown.Items = items;
            app.SelectEventsDropDown.Value = items{1}; 
        end

        function plotGridInApp(app, gridStruct,isplotLine)
            % 清空 Panel 并删除所有子对象
            delete(allchild(app.GridPanel));
            
            % 提取台站坐标
            stationX = gridStruct.rxInOriginalCoord(:, 1);
            stationY = gridStruct.ryInOriginalCoord(:, 2);
            
            % 提取网格点坐标
            XX = gridStruct.XInOriginalCoord;
            YY = gridStruct.YInOriginalCoord;
            
            %------------------- 2. 清空 Panel 并创建坐标轴 -------------------%
            gax = axes('Parent', app.GridPanel, ...
                'Units', 'normalized', ...
                'Position', [0.05 0.12 0.95 0.80]); % 调整坐标轴位置，留出边距
            hold(gax, 'on'); % 统一使用坐标轴句柄
            
            %------------------- 3. 绘制台站、网格点和投影点 -------------------%
            % 绘制台站（红色三角，较大尺寸）
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'LineWidth', 1.5, ...
                'MarkerEdgeColor', 'r', ...
                'DisplayName', '台站位置');
            
            % 绘制网格点（灰色点，适当缩小尺寸）
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', '网格点');
            
            %------------------- 4. 添加标签和图例 -------------------%
            xlabel(gax, '东向距离 (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(gax, '北向距离 (km)', 'FontSize', 12, 'FontWeight', 'bold');
            title(gax, '台站与网格分布图', 'FontSize', 14, 'FontWeight', 'bold');
            
            % 添加图例
            % legend(gax, 'show', 'Location', 'best', 'FontSize', 10);
            
            % 启用网格线
            box(gax,"on");
            grid(gax, 'on');
            grid(gax, 'minor'); % 添加次要网格线
            
            % 调整坐标轴比例，确保等比例显示
            axis(gax, 'equal');
            
            % 设置坐标轴范围，留出一定的边距
            xMargin = 0.1 * (max(XX(:)) - min(XX(:))); % 10% 的边距
            yMargin = 0.1 * (max(YY(:)) - min(YY(:))); % 10% 的边距
            xlim(gax, [min(XX(:)) - xMargin, max(XX(:)) + xMargin]);
            ylim(gax, [min(YY(:)) - yMargin, max(YY(:)) + yMargin]);
            
            % 设置坐标轴字体大小
            set(gax, 'FontSize', 10);
            
            % 设置背景颜色为白色
            set(gax, 'Color', 'w');
            set(app.GridPanel, 'BackgroundColor', 'w');

            if isplotLine
                coord1 = [app.X1EditField.Value,app.Y1EditField.Value];
                coord2 = [app.X2EditField.Value,app.Y2EditField.Value];
    
                plot(gax,[coord1(1),coord2(1)],[coord1(2),coord2(2)],'b-o','LineWidth',2)
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');  % 红色填充圆点
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
    
                text(gax,coord1(1),coord1(2),'A','FontSize',20,'HorizontalAlignment','right')
                text(gax,coord2(1),coord2(2),'B','FontSize',20,'HorizontalAlignment','left')

            end

            grid(gax, 'on');
            hold(gax, 'off');
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cmap = load(fullfile(pwd,'/visualization/colormap/roma.mat'));
            updateEventDropdown(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, '尚未读取数据！', '错误','Icon','error');
                return;
            end

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','Meshgrid', ...
                    'Message','Generate grid and velocity model...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            % create grid
            dx = app.dxkmEditField.Value;
            dy = app.dykmEditField.Value;
            dz = app.dzkmEditField.Value;
             % velocity model
            ModelType = app.VelocityModelDropDown.Value;
           
            app.gridStruct = createGrid(app.DataStruct, dx, dy, dz);
           
            if strcmp(ModelType,'Default')
                ModelType = '3D';
            end

            npts = 5;
            app.gridStruct = getVelocityModel(ModelType,app.gridStruct,npts);

            plotGridInApp(app,app.gridStruct,0);

            close(msg);
        end

        % Value changed function: SelectEventsDropDown
        function SelectEventsDropDownValueChanged(app, event)

            % 记录用户选的 event id
            selectedID = app.SelectEventsDropDown.Value;
            if strcmp(selectedID,'All')
                % 如果用户选择“All”，让 selectedEvid = 所有事件的 ID
                eList = getEvents(app.DataStruct);  % struct array of events
                app.selectedEvid = {eList.evid};    % 这里 eList.evid 本身可能是标量/数组
            else
                % 用户只选中了一个事件ID, 需要包装成 cell array
                app.selectedEvid = {selectedID};
            end
        end

        % Button pushed function: RunButtonLSM
        function RunButtonLSMPushed(app, event)
            shots = app.selectedEvid;
            if isempty(shots)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end
            if ~isfield(app.gridStruct,'VP')     
                uialert(app.UIFigure, 'Create grid first!', 'Note');
                return;
            end
            
            MigParam = app.MigrationParam;
            MigParam.paramMig = setMigParam3D(app.gridStruct);
            MigParam.paramMig.itermax = app.IterationEditField.Value;
            MigParam.paramMig.mu = app.MuEditField.Value;
            
            is_ssa = app.Is_SSADropDown.Value;
            if strcmp(is_ssa,'Default') | strcmp(is_ssa,'NO')
                is_ssa = 0;
            elseif strcmp(is_ssa,'YES')
                is_ssa = 1;

                RankReductionParam.nx = app.gridStruct.nx;
                RankReductionParam.ny = app.gridStruct.ny;
                RankReductionParam.ox = min(app.gridStruct.x);
                RankReductionParam.oy = min(app.gridStruct.y);
                RankReductionParam.mx = max(app.gridStruct.x);
                RankReductionParam.my = max(app.gridStruct.y);
                RankReductionParam.rank = app.RankEditField.Value;

            end
            MigParam.paramMig.isReconRFs = is_ssa;

            nshots = length(shots);
            dout = struct();
            for iEvent = 1:nshots
                msg = uiprogressdlg(app.UIFigure, ...
                    'Title','LSM', ...
                    'Message',['LSM begins ',num2str(iEvent),' of ',num2str(nshots),' ...'], ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');

                evid = shots{iEvent};
                
                gather = getCommonEventGather(app.DataStruct, evid);

                if is_ssa
                    [gather, d1_otg] = rankReduction_new(gather, app.gridStruct, RankReductionParam);
                    MigParam.paramMig.dotg = d1_otg;
                end
                % Perform 3D least-squares migration
                % This method provides improved resolution compared to standard migration
                mig = leastSquaresMig3D(gather, app.gridStruct, MigParam);
                
                dout(iEvent).images = mig;

            end
            app.migResult = dout;
            close(msg)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% plot results

            plotGridInApp(app,app.gridStruct,1);

            %% 

            if isempty(app.migResult)
                uialert(app.UIFigure, 'no data!', 'Note');
                return;

            end
            x1 = app.X1EditField.Value;
            x2 = app.X2EditField.Value;
            y1 = app.Y1EditField.Value;
            y2 = app.Y2EditField.Value;

            dout = app.migResult;       % all events
            
            V1 = zeros(size(dout(1).images.mig));
            V2 = V1;
            for n=1:length(dout)
                V1 = V1 + dout(n).images.mig;
                V2 = V2 + dout(n).images.migls;
            end
            V1 = V1/length(dout);
            V1 = permute(V1,[3,2,1]);

            V2 = V2/length(dout);
            V2 = permute(V2,[3,2,1]);


            X = dout(1).images.X;
            Y = dout(1).images.Y;
            Z = dout(1).images.Z;
            [~,~,nz] = size(X);

            % shotsID = app.selectedEvid;
            Fi = scatteredInterpolant(X(:),Y(:),Z(:),V2(:));

            ninterp = 50;
            xi = linspace(x1,x2,ninterp);
            yi = linspace(y1,y2,ninterp);
            depth = squeeze(Z(1,1,:));

            xdist = linspace(0,sqrt((x1-x2)^2 + (y1-y2)^2),ninterp);

            xpro = repmat(xi,nz,1);
            ypro = repmat(yi,nz,1);
            zpro = repmat(depth,1,ninterp);

            Vpro = Fi(xpro,ypro,zpro);


            cla(app.UIAxes_lsm,"reset");  
            % axes(app.UIAxes_lsm);
            % hold(app.UIAxes_lsm, 'on'); 
            imagesc(app.UIAxes_lsm, xdist, depth, Vpro); 
            
            % 设置坐标轴属性
            axis(app.UIAxes_lsm, 'equal'); % 等比例坐标轴
            title(app.UIAxes_lsm,'LSM image')
            xlabel(app.UIAxes_lsm, 'Distance (km)');
            ylabel(app.UIAxes_lsm, 'Depth (km)');
            xlim(app.UIAxes_lsm, [0, max(xdist)]);
            ylim(app.UIAxes_lsm, [0, 100]);
            text(app.UIAxes_lsm,0,-5,'A','FontSize',14)
            text(app.UIAxes_lsm,max(xdist)-5,-5,'B','FontSize',14)
                        
            % 设置颜色范围（确保使用正确的数据计算）
            cmax = rms(Vpro(:)); % 假设需基于Vpro而非V_smooth
            caxis(app.UIAxes_lsm, [-3*cmax, 3*cmax]); % 显式指定坐标轴
            % caxis(app.CCPImageMap, [-1 1]);
            
            % 可选：设置颜色图
%             colormap(app.CCPImageMap, 'jet'); % 例如使用jet颜色图
            colormap(app.UIAxes_lsm,flipud(app.cmap.roma));
            colorbar(app.UIAxes_lsm)


        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            answer = questdlg('Exist?', '', 'YES', 'NO', 'NO');
            % 根据用户选择执行操作
            switch answer
                case 'YES'
                    delete(app.UIFigure); % 删除主窗口以退出
                case 'NO'
                    return; % 取消关闭操作
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.SyntheticDataMenu = uimenu(app.ImportMenu);
            app.SyntheticDataMenu.Text = 'Synthetic Data';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes_lsm = uiaxes(app.UIFigure);
            title(app.UIAxes_lsm, 'LSM image')
            xlabel(app.UIAxes_lsm, 'Distance (km)')
            ylabel(app.UIAxes_lsm, 'Depth (km)')
            zlabel(app.UIAxes_lsm, 'Z')
            app.Migration3DPanelLabel = uilabel(app.UIFigure);
            app.Migration3DPanelLabel.Text = '3D Migration Panel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.CreateGridPanel = uipanel(app.UIFigure);
            app.CreateGridPanel.Title = 'Create Grid';
            app.CreateButton = uibutton(app.CreateGridPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.VelocityModelDropDownLabel = uilabel(app.CreateGridPanel);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model:';
            app.VelocityModelDropDown = uidropdown(app.CreateGridPanel);
            app.VelocityModelDropDown.Items = {'1D', '3D', 'Default'};
            app.VelocityModelDropDown.Value = 'Default';
            app.dykmEditFieldLabel = uilabel(app.CreateGridPanel);
            app.dykmEditFieldLabel.Text = 'dy (km):';
            app.dykmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dykmEditField.Value = 5;
            app.dzkmEditFieldLabel = uilabel(app.CreateGridPanel);
            app.dzkmEditFieldLabel.Text = 'dz (km):';
            app.dzkmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dzkmEditField.Value = 1;
            app.dxLabel = uilabel(app.CreateGridPanel);
            app.dxLabel.Text = 'dx (km):';
            app.dxkmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dxkmEditField.Value = 5;
            app.PlotparametersPanel = uipanel(app.UIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters:';
            app.X1EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditField.Value = 200;
            app.Y2EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y2EditField.Value = 130;
            app.PlotButton = uibutton(app.PlotparametersPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.PlotparametersPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.SelectEventsDropDown = uidropdown(app.UIFigure);
            app.SelectEventsDropDown.Items = {'Select Events'};
            app.SelectEventsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEventsDropDownValueChanged, true);
            app.SelectEventsDropDown.Value = 'Select Events';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SSAParTab = uitab(app.TabGroup);
            app.SSAParTab.Title = 'SSA Par';
            app.Is_SSALabel = uilabel(app.SSAParTab);
            app.Is_SSALabel.Text = 'Is_SSA:';
            app.Is_SSADropDown = uidropdown(app.SSAParTab);
            app.Is_SSADropDown.Items = {'Default', 'YES', 'NO'};
            app.Is_SSADropDown.Value = 'Default';
            app.RankEditFieldLabel = uilabel(app.SSAParTab);
            app.RankEditFieldLabel.Text = 'Rank:';
            app.RankEditField = uieditfield(app.SSAParTab, 'numeric');
            app.RankEditField.Value = 8;
            app.AlphaEditFieldLabel = uilabel(app.SSAParTab);
            app.AlphaEditFieldLabel.Text = 'Alpha:';
            app.AlphaEditField = uieditfield(app.SSAParTab, 'numeric');
            app.AlphaEditField.Value = 0.9;
            app.LSMParTab = uitab(app.TabGroup);
            app.LSMParTab.Title = 'LSM Par';
            app.RunButtonLSM = uibutton(app.LSMParTab, 'push');
            app.RunButtonLSM.ButtonPushedFcn = createCallbackFcn(app, @RunButtonLSMPushed, true);
            app.RunButtonLSM.Text = 'Run';
            app.IterationEditField_2Label = uilabel(app.LSMParTab);
            app.IterationEditField_2Label.Text = 'Iteration:';
            app.IterationEditField = uieditfield(app.LSMParTab, 'numeric');
            app.IterationEditField.Value = 20;
            app.MuEditFieldLabel = uilabel(app.LSMParTab);
            app.MuEditFieldLabel.Text = 'Mu:';
            app.MuEditField = uieditfield(app.LSMParTab, 'numeric');
            app.MuEditField.Value = 0.1;
            app.GridPanel = uipanel(app.UIFigure);
            app.GridPanel.Title = 'Grid Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Migration3DApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end