classdef PreprocessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditDoneButton                  matlab.ui.control.Button
        SelectCommonEventGatherPanel    matlab.ui.container.Panel
        RunPreprocessingButtonGather    matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        EventSelectionDropDown          matlab.ui.control.DropDown
        SelectCommonStationGatherPanel  matlab.ui.container.Panel
        RunPreprocessingButtonGather_2  matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        PreviousButton_2                matlab.ui.control.Button
        StationSelectionDropDown        matlab.ui.control.DropDown
        resample_periodEditField        matlab.ui.control.NumericEditField
        resample_periodEditFieldLabel   matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        DeleteTraceButton               matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        sig_leaderEditField             matlab.ui.control.NumericEditField
        sig_leaderEditFieldLabel        matlab.ui.control.Label
        record_lenEditField             matlab.ui.control.NumericEditField
        record_lenEditFieldLabel        matlab.ui.control.Label
        freqmaxEditField                matlab.ui.control.NumericEditField
        freqmaxEditFieldLabel           matlab.ui.control.Label
        freqminEditField                matlab.ui.control.NumericEditField
        freqminEditFieldLabel           matlab.ui.control.Label
        statusLabel                     matlab.ui.control.Label
        processingWorkflowLabel         matlab.ui.control.Label
        PreprocessingParametersLabel    matlab.ui.control.Label
        RunAllButton                    matlab.ui.control.Button
        PreprocessingPanelLabel         matlab.ui.control.Label
        UIAxesTransverse                matlab.ui.control.UIAxes
        UIAxesRadial                    matlab.ui.control.UIAxes
        UIAxesVertical                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DataStruct                                                         % Storage for seismic data
        PreprocessingParam                                                 % Storage for processing parameters  
        mainApp                                                            % main App
    end
    
    properties (Access = private)
        gather                                                             % Selected gather
        selectedEvid                                                       % Storage for selected event IDs
        selectedStacode                                                    % Storage for selected station codes
        matchIndex                                                         % Indices corresponding to the gather
        LineHandlesVertical                                                % Array of line object handles for vertical component
        LineHandlesRadial                                                  % Array of line object handles for radial component  
        LineHandlesTransverse                                              % Array of line object handles for transverse component
        SelectedTraces                                                     % Indices of selected traces
        nTraces                                                            % Number of traces
        TraceSpacing = 1;                                                  % Trace spacing
    end

    methods (Access = public)
        function loadConfigData(app, defaultParam)
            
            app.PreprocessingParam = defaultParam;
            app.sig_leaderEditField.Value = defaultParam.sig_leader;
            app.record_lenEditField.Value = defaultParam.record_len;
            app.freqminEditField.Value = defaultParam.lows;
            app.freqmaxEditField.Value = defaultParam.highs;
            app.resample_periodEditField.Value = defaultParam.resample_period;
            % update event list
            updateEventDropdown(app)
            % update station list
            updateStationDropdown(app)
        end
    end
    
    methods (Access = private)
        function updateEventDropdown(app)
            if isempty(app.DataStruct)
                app.EventSelectionDropDown.Items = {'(no events)'};
                app.EventSelectionDropDown.Value = '(no events)';
                return;
            end

            eList = getEvents(app.DataStruct);
            eIDs  = {eList.evid};
            items = [{'None'}, eIDs];

            app.EventSelectionDropDown.Items = items;
            app.EventSelectionDropDown.Value = 'None'; % or items{1}
        end

        function updateStationDropdown(app)
            if isempty(app.DataStruct)
                app.StationSelectionDropDown.Items = {'(no stations)'};
                app.StationSelectionDropDown.Value = '(no stations)';
                return;
            end

            sList = getStations(app.DataStruct);
            stacodes  = {sList.sta};
            items = [{'None'}, stacodes];

            app.StationSelectionDropDown.Items = items;
            app.StationSelectionDropDown.Value = 'None'; % or items{1}
        end

        function plotTraces(app)
            %%  vertical component axis
            cla(app.UIAxesVertical)
           
            app.UIAxesVertical.Box = 'on';
%             app.UIAxesVertical.XTick = [];
            app.UIAxesVertical.YDir = 'reverse';

            app.nTraces = length(app.gather);
            app.UIAxesVertical.XLim = [0 app.nTraces+1];

            app.TraceSpacing = 1;
            hold(app.UIAxesVertical, 'on');
            app.LineHandlesVertical = gobjects(app.nTraces, 1);

            for i = 1:app.nTraces
                if isfield(app.gather(i).Waveforms, 'dataProcessed') && ~isempty(app.gather(i).Waveforms.dataProcessed)
                    traceData = app.gather(i).Waveforms.dataProcessed(:,3) - mean(app.gather(i).Waveforms.dataProcessed(:,3));
                    dt = app.gather(i).TimeAxis.dt_resample;
                else
                    traceData = app.gather(i).Waveforms.data(:,3) - mean(app.gather(i).Waveforms.data(:,3));
                    dt = app.gather(i).TimeAxis.dt;
                    app.UIAxesVertical.Title.String = 'Vertical Component';
                end

                traceData = traceData/max(traceData);
                xData = traceData+i*app.TraceSpacing;
                t = (0:length(xData)-1)*dt;

                app.LineHandlesVertical(i) = plot(app.UIAxesVertical,xData,t,...
                    'Color', [0.2 0.2 0.2],...
                    'LineWidth', 0.5,...
                    'PickableParts', 'none',...  
                    'HitTest', 'off');
            end
            hold(app.UIAxesVertical, 'off');
                        
            app.SelectedTraces = [];
            updateStatus(app);

            %% Radial component axis
            cla(app.UIAxesRadial)
           
            app.UIAxesRadial.Box = 'on';
%             app.UIAxesRadial.XTick = [];
            app.UIAxesRadial.YDir = 'reverse';
            app.UIAxesRadial.XLim = [0 app.nTraces+1];

            app.nTraces = length(app.gather);
            app.TraceSpacing = 1;
            hold(app.UIAxesRadial, 'on');
            app.LineHandlesRadial = gobjects(app.nTraces, 1);

            for i = 1:app.nTraces
                if isfield(app.gather(i).Waveforms, 'dataProcessed') && ~isempty(app.gather(i).Waveforms.dataProcessed)
                    traceData = app.gather(i).Waveforms.dataProcessed(:,2) - mean(app.gather(i).Waveforms.dataProcessed(:,2));
                    dt = app.gather(i).TimeAxis.dt_resample;
                    app.UIAxesRadial.Title.String = 'Radial Component';
                else
                    traceData = app.gather(i).Waveforms.data(:,2) - mean(app.gather(i).Waveforms.data(:,2));
                    app.UIAxesRadial.Title.String = 'North Component';
                    dt = app.gather(i).TimeAxis.dt;
                end

                traceData = traceData/max(traceData);
                xData = traceData+i*app.TraceSpacing;
                t = (0:length(xData)-1)*dt;

                app.LineHandlesRadial(i) = plot(app.UIAxesRadial,xData,t,...
                    'Color', [0.2 0.2 0.2],...
                    'LineWidth', 0.5,...
                    'PickableParts', 'none',...  
                    'HitTest', 'off');
            end
            hold(app.UIAxesRadial, 'off');

            %% Transverse component axis
            cla(app.UIAxesTransverse)
           
            app.UIAxesTransverse.Box = 'on';
%             app.UIAxesTransverse.XTick = [];
            app.UIAxesTransverse.YDir = 'reverse';
            app.UIAxesTransverse.XLim = [0 app.nTraces+1];

            app.nTraces = length(app.gather);
            app.TraceSpacing = 1;
            hold(app.UIAxesTransverse, 'on');
            app.LineHandlesTransverse = gobjects(app.nTraces, 1);

            for i = 1:app.nTraces
                if isfield(app.gather(i).Waveforms, 'dataProcessed') && ~isempty(app.gather(i).Waveforms.dataProcessed)
                    traceData = app.gather(i).Waveforms.dataProcessed(:,1) - mean(app.gather(i).Waveforms.dataProcessed(:,1));
                    dt = app.gather(i).TimeAxis.dt_resample;
                    app.UIAxesTransverse.Title.String = 'Transverse Component';
                else
                    traceData = app.gather(i).Waveforms.data(:,1) - mean(app.gather(i).Waveforms.data(:,1));
                    app.UIAxesTransverse.Title.String = 'East Component';
                    dt = app.gather(i).TimeAxis.dt;
                end

                traceData = traceData/max(traceData);
                xData = traceData+i*app.TraceSpacing;
                t = (0:length(xData)-1)*dt;

                 app.LineHandlesTransverse(i) = plot(app.UIAxesTransverse,xData,t,...
                    'Color', [0.2 0.2 0.2],...
                    'LineWidth', 0.5,...
                    'PickableParts', 'none',... 
                    'HitTest', 'off');
            end
            hold(app.UIAxesTransverse, 'off');
        end

        function updateSelectionDisplay(app)
            set(app.LineHandlesVertical,  'Color', [0.2 0.2 0.2]);
            set(app.LineHandlesRadial,    'Color', [0.2 0.2 0.2]);
            set(app.LineHandlesTransverse,'Color', [0.2 0.2 0.2]);
            
            if ~isempty(app.SelectedTraces)
                set(app.LineHandlesVertical(app.SelectedTraces), 'Color', [1 0 0]);
                set(app.LineHandlesRadial(app.SelectedTraces), 'Color', [1 0 0]);
                set(app.LineHandlesTransverse(app.SelectedTraces), 'Color', [1 0 0]);
            end
            
            drawnow limitrate;
        end

       function updateStatus(app)
            statusText = sprintf('Selected %d traces: %s',...
                numel(app.SelectedTraces),...
                mat2str(sort(app.SelectedTraces)));
            app.statusLabel.Text = statusText;
        end

        function traceIdx = getTraceFromClick(app, event)

            clickPos = event.IntersectionPoint(1);  
            traceIdx = round(clickPos / app.TraceSpacing);
            
            traceIdx = max(1, min(app.nTraces, traceIdx));
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             setupData(app);
            app.UIFigure.Name = 'Preprocessing App';
        end

        % Value changed function: EventSelectionDropDown
        function onEventSelectionChanged(app, event)
            selected = app.EventSelectionDropDown.Value;
            % plot gather
            [app.gather, app.matchIndex]   = getCommonEventGather(app.DataStruct, selected);
            plotTraces(app);
            app.selectedEvid = {selected};
        end

        % Value changed function: StationSelectionDropDown
        function onStationSelectionChanged(app, event)

            selected = app.StationSelectionDropDown.Value;
            % plot gather
            [app.gather, app.matchIndex]   = getCommonStationGather(app.DataStruct, selected);
            plotTraces(app);
            app.selectedStacode = {selected};
        end

        % Button pushed function: RunPreprocessingButtonGather
        function RunPreprocessingButton(app, event)

            eSelected = app.EventSelectionDropDown.Value; 
            
            if  strcmp(eSelected,'None')
                uialert(app.UIFigure, 'Please select a specific event', 'Error');
                return;
            elseif (~strcmp(eSelected,'None'))

                [app.gather, ~]   = getCommonEventGather(app.DataStruct, eSelected);
                gatherSelected = eSelected;
            end

            if isempty(app.gather)
                uialert(app.UIFigure, 'Failed to access selected gather data', 'Error');
                return;
            end
            % app.PreprocessingParam
            app.gather = preprocessing(app.gather, app.PreprocessingParam);

            plotTraces(app);
            app.statusLabel.Text = ['Gather ', gatherSelected, ' processing finished'];

        end

        % Button down function: UIAxesRadial, UIAxesTransverse, 
        % ...and 1 other component
        function axesClicked(app, event)
            traceIdx = app.getTraceFromClick(event);
            
            if ismember(traceIdx, app.SelectedTraces)
                app.SelectedTraces(app.SelectedTraces == traceIdx) = [];
            else
                app.SelectedTraces = union(app.SelectedTraces, traceIdx);
            end
            
            app.updateSelectionDisplay();
            app.updateStatus();
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)

            app.mainApp.config.PreprocessingParam = app.PreprocessingParam;
            app.mainApp.DataStruct = app.DataStruct;
            app.mainApp.preprocessingStatus = 'OK';

            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.preprocessingStatus = 'Cancel';
            delete(app.UIFigure);
        end

        % Value changed function: freqminEditField
        function freqminEditFieldValueChanged(app, event)
            value = app.freqminEditField.Value;
            app.PreprocessingParam.lows = value;
        end

        % Value changed function: freqmaxEditField
        function freqmaxEditFieldValueChanged(app, event)
            value = app.freqmaxEditField.Value;
            app.PreprocessingParam.highs = value;
        end

        % Value changed function: sig_leaderEditField
        function sig_leaderEditFieldValueChanged(app, event)
            value = app.sig_leaderEditField.Value;
            app.PreprocessingParam.sig_leader = value;
        end

        % Value changed function: record_lenEditField
        function record_lenEditFieldValueChanged(app, event)
            value = app.record_lenEditField.Value;
            app.PreprocessingParam.record_len = value;
        end

        % Value changed function: resample_periodEditField
        function resample_periodEditFieldValueChanged(app, event)
            value = app.resample_periodEditField.Value;
            app.PreprocessingParam.resample_period = value;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            currentEvid = app.EventSelectionDropDown.Value;
            eIDs = app.EventSelectionDropDown.Items;
            eIdx = find(strcmp(currentEvid,eIDs));
            if eIdx < length(eIDs)
                currentEvid = app.EventSelectionDropDown.Items{eIdx+1};
                app.EventSelectionDropDown.Value = currentEvid;
                % plot gather
                [app.gather, app.matchIndex]   = getCommonEventGather(app.DataStruct, currentEvid);
                plotTraces(app);
                statusText = sprintf('%d records match EventID "%s".\n', length(app.gather), currentEvid);
                app.statusLabel.Text = statusText;
            else
                uialert(app.UIFigure, 'Please select another event', 'Warning');
                return;
            end

        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            currentEvid = app.EventSelectionDropDown.Value;
            eIDs = app.EventSelectionDropDown.Items;
            eIdx = find(strcmp(currentEvid,eIDs));
            if eIdx > 1
                currentEvid = app.EventSelectionDropDown.Items{eIdx-1};
                app.EventSelectionDropDown.Value = currentEvid;
                 % plot gather
                [app.gather, app.matchIndex]   = getCommonEventGather(app.DataStruct, currentEvid);
                plotTraces(app);
                statusText = sprintf('%d records match EventID "%s".\n', length(app.gather), currentEvid);
                app.statusLabel.Text = statusText;
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            currentStacode = app.StationSelectionDropDown.Value;
            stacodes = app.StationSelectionDropDown.Items;
            sIdx = find(strcmp(currentStacode,stacodes));
            if sIdx < length(stacodes)
                currentStacode = app.StationSelectionDropDown.Items{sIdx+1};
                app.StationSelectionDropDown.Value = currentStacode;
                % plot gather
                [app.gather, app.matchIndex]   = getCommonStationGather(app.DataStruct, currentStacode);
                plotTraces(app);
                statusText = sprintf('%d events recored by "%s".\n', length(app.gather), currentStacode);
                app.statusLabel.Text = statusText;
            else
                uialert(app.UIFigure, 'Please select another station', 'Warning');
                return;
            end
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            currentStacode = app.StationSelectionDropDown.Value;
            stacodes = app.StationSelectionDropDown.Items;
            sIdx = find(strcmp(currentStacode,stacodes));
            if sIdx > 1
                currentStacode = app.StationSelectionDropDown.Items{sIdx-1};
                app.StationSelectionDropDown.Value = currentStacode;
                % plot gather
                [app.gather, app.matchIndex] = getCommonStationGather(app.DataStruct, currentStacode);
                plotTraces(app);
                statusText = sprintf('%d events recored by "%s".\n', length(app.gather), currentStacode);
                app.statusLabel.Text = statusText;
            end
        end

        % Button pushed function: RunPreprocessingButtonGather_2
        function RunPreprocessingButtonGather_2Pushed(app, event)

            sSelected = app.StationSelectionDropDown.Value; 
    
            if  strcmp(sSelected,'None')
                uialert(app.UIFigure, 'Please select a specific station', 'Error');
                return;
            elseif (~strcmp(sSelected,'(no stations)'))
                [app.gather, ~]   = getCommonStationGather(app.DataStruct, sSelected);
                gatherSelected = sSelected;
            end

            if isempty(app.gather)
                uialert(app.UIFigure, 'Fail to retrieve selected gather data', 'Error');
                return;
            end
            app.PreprocessingParam
            app.gather = preprocessing(app.gather, app.PreprocessingParam);
            % 绘制波形
            plotTraces(app);
            app.statusLabel.Text = ['Gather ', gatherSelected, ' processing completed'];
        end

        % Button pushed function: DeleteTraceButton
        function DeleteTraceButtonPushed(app, event)
            app.gather(app.SelectedTraces) = [];
            plotTraces(app);
        end

        % Button pushed function: EditDoneButton
        function EditDoneButtonPushed(app, event)
            DataStruct = app.DataStruct;
            DataStruct(app.matchIndex) = [];
            app.DataStruct = [DataStruct app.gather];
            statusText = sprintf('Delete %d records\n', length(app.matchIndex)-length(app.gather));
            app.statusLabel.Text = statusText;
            % 同时更新事件列表
%             updateEventDropdown(app)
            % 更新台站列表
%             updateStationDropdown(app)
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data has been loaded!', 'Error', 'Icon', 'error');
                return;
            end
            if ~isprop(app,'PreprocessingParam')
                uialert(app.UIFigure, 'PreprocessingParam is missing from configuration!', 'Error', 'Icon', 'error');
                return;
            end
            try
                d = uiprogressdlg(app.UIFigure, ...
                    'Title','Preprocessing', ...
                    'Message','Preprocessing, please wait...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');

                nFiles = length(app.DataStruct);
                tic
                for i = 1:nFiles
                    % update status
                    d.Value = (i-1)/nFiles;
                    d.Message = sprintf('Processing: (%d/%d) trace', i, nFiles);
                    drawnow;  
       
                    % call preprocessing_single(...)
                    [app.DataStruct(i),removeFlag] = preprocessing_single(app.DataStruct(i), app.PreprocessingParam);
                end
         
                d.Value = 1;
                d.Message = 'Preprocessing completed!';
                drawnow;
        
                pause(0.5); 
                close(d);
        
                uialert(app.UIFigure, 'Preprocessing done!', 'Success');
            catch ME
                if exist('d','var') && isvalid(d)
                    close(d);
                end
                uialert(app.UIFigure, ['Preprocessing failed', ME.message], 'Error','Icon','error');
            end
            app.statusLabel.Text = 'Preprocessing done';
            toc
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesVertical = uiaxes(app.UIFigure);
            title(app.UIAxesVertical, 'Vertical component')
            xlabel(app.UIAxesVertical, 'Trace')
            ylabel(app.UIAxesVertical, 'Time (s)')
            zlabel(app.UIAxesVertical, 'Z')
            app.UIAxesVertical.ButtonDownFcn = createCallbackFcn(app, @axesClicked, true);
            app.UIAxesRadial = uiaxes(app.UIFigure);
            title(app.UIAxesRadial, 'Radial component')
            xlabel(app.UIAxesRadial, 'Trace')
            ylabel(app.UIAxesRadial, 'Time (s)')
            zlabel(app.UIAxesRadial, 'Z')
            app.UIAxesRadial.ButtonDownFcn = createCallbackFcn(app, @axesClicked, true);
            app.UIAxesTransverse = uiaxes(app.UIFigure);
            title(app.UIAxesTransverse, 'Transverse component')
            xlabel(app.UIAxesTransverse, 'Trace')
            ylabel(app.UIAxesTransverse, 'Time (s)')
            zlabel(app.UIAxesTransverse, 'Z')
            app.UIAxesTransverse.ButtonDownFcn = createCallbackFcn(app, @axesClicked, true);
            app.PreprocessingPanelLabel = uilabel(app.UIFigure);
            app.PreprocessingPanelLabel.Text = 'Preprocessing Panel';
            app.RunAllButton = uibutton(app.UIFigure, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.PreprocessingParametersLabel = uilabel(app.UIFigure);
            app.PreprocessingParametersLabel.Text = 'Preprocessing Parameters';
            app.processingWorkflowLabel = uilabel(app.UIFigure);
            app.processingWorkflowLabel.Text = 'Status:';
            app.statusLabel = uilabel(app.UIFigure);
            app.statusLabel.Text = '';
            app.freqminEditFieldLabel = uilabel(app.UIFigure);
            app.freqminEditFieldLabel.Text = 'freqmin';
            app.freqminEditField = uieditfield(app.UIFigure, 'numeric');
            app.freqminEditField.ValueChangedFcn = createCallbackFcn(app, @freqminEditFieldValueChanged, true);
            app.freqminEditField.Value = 0.1;
            app.freqmaxEditFieldLabel = uilabel(app.UIFigure);
            app.freqmaxEditFieldLabel.Text = 'freqmax';
            app.freqmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.freqmaxEditField.ValueChangedFcn = createCallbackFcn(app, @freqmaxEditFieldValueChanged, true);
            app.freqmaxEditField.Value = 1.2;
            app.record_lenEditFieldLabel = uilabel(app.UIFigure);
            app.record_lenEditFieldLabel.Text = 'record_len';
            app.record_lenEditField = uieditfield(app.UIFigure, 'numeric');
            app.record_lenEditField.ValueChangedFcn = createCallbackFcn(app, @record_lenEditFieldValueChanged, true);
            app.record_lenEditField.Value = 100;
            app.sig_leaderEditFieldLabel = uilabel(app.UIFigure);
            app.sig_leaderEditFieldLabel.Text = 'before P';
            app.sig_leaderEditField = uieditfield(app.UIFigure, 'numeric');
            app.sig_leaderEditField.ValueChangedFcn = createCallbackFcn(app, @sig_leaderEditFieldValueChanged, true);
            app.sig_leaderEditField.Value = 30;
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DeleteTraceButton = uibutton(app.UIFigure, 'push');
            app.DeleteTraceButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTraceButtonPushed, true);
            app.DeleteTraceButton.Text = 'Delete Trace';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.resample_periodEditFieldLabel = uilabel(app.UIFigure);
            app.resample_periodEditFieldLabel.Text = {'resample'; 'period'};
            app.resample_periodEditField = uieditfield(app.UIFigure, 'numeric');
            app.resample_periodEditField.ValueChangedFcn = createCallbackFcn(app, @resample_periodEditFieldValueChanged, true);
            app.resample_periodEditField.Value = 0.1;
            app.SelectCommonStationGatherPanel = uipanel(app.UIFigure);
            app.SelectCommonStationGatherPanel.Title = 'Select Common Station Gather';
            app.StationSelectionDropDown = uidropdown(app.SelectCommonStationGatherPanel);
            app.StationSelectionDropDown.Items = {};
            app.StationSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @onStationSelectionChanged, true);
            app.StationSelectionDropDown.Value = {};
            app.PreviousButton_2 = uibutton(app.SelectCommonStationGatherPanel, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = 'Previous';
            app.NextButton_2 = uibutton(app.SelectCommonStationGatherPanel, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.RunPreprocessingButtonGather_2 = uibutton(app.SelectCommonStationGatherPanel, 'push');
            app.RunPreprocessingButtonGather_2.ButtonPushedFcn = createCallbackFcn(app, @RunPreprocessingButtonGather_2Pushed, true);
            app.RunPreprocessingButtonGather_2.Text = 'Run Preprocessing';
            app.SelectCommonEventGatherPanel = uipanel(app.UIFigure);
            app.SelectCommonEventGatherPanel.Title = 'Select Common Event Gather';
            app.EventSelectionDropDown = uidropdown(app.SelectCommonEventGatherPanel);
            app.EventSelectionDropDown.Items = {};
            app.EventSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @onEventSelectionChanged, true);
            app.EventSelectionDropDown.Value = {};
            app.NextButton = uibutton(app.SelectCommonEventGatherPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.SelectCommonEventGatherPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.RunPreprocessingButtonGather = uibutton(app.SelectCommonEventGatherPanel, 'push');
            app.RunPreprocessingButtonGather.ButtonPushedFcn = createCallbackFcn(app, @RunPreprocessingButton, true);
            app.RunPreprocessingButtonGather.Text = 'Run Preprocessing';
            app.EditDoneButton = uibutton(app.UIFigure, 'push');
            app.EditDoneButton.ButtonPushedFcn = createCallbackFcn(app, @EditDoneButtonPushed, true);
            app.EditDoneButton.Text = 'Edit Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreprocessingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end