classdef RankReductionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoaddataMenu                  matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        PlotComparisonButton          matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        RunRankReductionButton        matlab.ui.control.Button
        EventSelectionDropDown        matlab.ui.control.DropDown
        SelectEventDropDownLabel      matlab.ui.control.Label
        RankReductionParametersPanel  matlab.ui.container.Panel
        verboseCheckBox               matlab.ui.control.CheckBox
        modeEditField                 matlab.ui.control.NumericEditField
        modeEditFieldLabel            matlab.ui.control.Label
        tmaxEditField                 matlab.ui.control.NumericEditField
        tmaxEditFieldLabel            matlab.ui.control.Label
        epsEditField                  matlab.ui.control.NumericEditField
        epsEditFieldLabel             matlab.ui.control.Label
        niterEditField                matlab.ui.control.NumericEditField
        niterEditFieldLabel           matlab.ui.control.Label
        KEditField                    matlab.ui.control.NumericEditField
        KEditFieldLabel               matlab.ui.control.Label
        rankEditField                 matlab.ui.control.NumericEditField
        rankEditFieldLabel            matlab.ui.control.Label
        fhighEditField                matlab.ui.control.NumericEditField
        fhighEditFieldLabel           matlab.ui.control.Label
        flowEditField                 matlab.ui.control.NumericEditField
        flowEditFieldLabel            matlab.ui.control.Label
        GridParametersPanel           matlab.ui.container.Panel
        dykmEditField                 matlab.ui.control.NumericEditField
        dykmEditFieldLabel            matlab.ui.control.Label
        dxkmEditField                 matlab.ui.control.NumericEditField
        dxkmEditFieldLabel            matlab.ui.control.Label
        CreateGridButton              matlab.ui.control.Button
        statusLabel                   matlab.ui.control.Label
        processingWorkflowLabel       matlab.ui.control.Label
        RankReductionPanelLabel       matlab.ui.control.Label
        RawWaveformUIAxes             matlab.ui.control.UIAxes
        DenoisedWaveformUIAxes        matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DataStructDRR 
        gridStruct 
        Events 
        Stations  
        selectedEvid
        selectedStacode
        cmap
    end
    
    properties (Access = public)
        DataStruct         
        RankReductionParam  
        mainApp            
    end
    
    methods (Access = public)
        
        function loadConfigData(app, defaultParam)
            
            app.RankReductionParam = defaultParam;

            app.fhighEditField.Value = defaultParam.fhigh;
            app.flowEditField.Value = defaultParam.flow;
            app.dxkmEditField.Value = defaultParam.dx;
            app.dykmEditField.Value = defaultParam.dy;
            app.rankEditField.Value = defaultParam.rank;
            app.niterEditField.Value = defaultParam.niter;
            app.modeEditField.Value = defaultParam.mode;
            app.verboseCheckBox.Value = defaultParam.verb;
            app.epsEditField.Value = defaultParam.eps;
            app.KEditField.Value = defaultParam.K;
            app.tmaxEditField.Value = defaultParam.tmax;

            updateEventDropdown(app)
        end
    end
    
    methods (Access = private)
        
        function updateEventDropdown(app)
            if isempty(app.DataStruct)
                app.EventSelectionDropDown.Items = {'(no events)'};
                app.EventSelectionDropDown.Value = '(no events)';
                return;
            end

            eList = getEvents(app.DataStruct);
            eIDs  = {eList.evid};
            items = [{'None','All'}, eIDs];

            app.EventSelectionDropDown.Items = items;
            app.EventSelectionDropDown.Value = 'None'; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'RankReduction App';
            app.cmap = load(fullfile(pwd,'visualization/colormap/roma.mat'));
            updateEventDropdown(app)
        end

        % Button pushed function: CreateGridButton
        function CreateGridButtonPushed(app, event)
            dx = app.dxkmEditField.Value;
            dy = app.dykmEditField.Value;
            app.gridStruct = createGrid(app.DataStruct, dx, dy,1,100,5,5);

            gather = app.DataStruct;
            stationList = getStations(gather);
            stlo = [stationList.stlo]';  
            stla = [stationList.stla]';
            [rx, ry] = latlonToProjectedCoords(stlo, stla, app.gridStruct);

            nx = app.gridStruct.nx;
            ny = app.gridStruct.ny;
            ox = min(app.gridStruct.x);
            oy = min(app.gridStruct.y);
            mx = max(app.gridStruct.x);
            my = max(app.gridStruct.y);

            xx= ox+(0:nx-1)*dx;
            yy= oy+(0:ny-1)*dy;
            [Xgrid,Ygrid] = meshgrid(xx,yy);

            delete(allchild(app.UIAxes));
            scatter(app.UIAxes,rx,ry,30,'b^'); hold(app.UIAxes, 'on');
            scatter(app.UIAxes, Xgrid, Ygrid, 20, 'o', 'filled', 'MarkerFaceColor', [0.5 0.5 0.5])
            set(app.UIAxes, 'xlim',[ox-10 mx+10], 'ylim',[oy-10 my+10]);
            hold(app.UIAxes, 'off')
            
            app.RankReductionParam.rx = rx;
            app.RankReductionParam.ry = ry;
        
            app.RankReductionParam.nx = nx;
            app.RankReductionParam.ny = ny;
            app.RankReductionParam.ox = ox;
            app.RankReductionParam.oy = oy;
            app.RankReductionParam.mx = mx;
            app.RankReductionParam.my = my;

            app.statusLabel.Text = 'Grid construction done!';
        end

        % Menu selected function: LoaddataMenu
        function LoaddataMenuSelected(app, event)
            try
                [filename, pathname] = uigetfile({'*.mat'}, 'Please select the file');
                if isequal(filename,0) || isequal(pathname,0)
                    return;
                end
                fullpath = fullfile(pathname, filename);

                loaded = load(fullpath);

                if isfield(loaded, 'DataStructDRR')
                    
                    app.DataStruct = loaded.DataStructDRR;
                   
                    fprintf('DataStructDRR loaded successfully, size = %d records.\n', numel(loaded.DataStructDRR));
                elseif isfield(loaded, 'DataStruct')

                    app.DataStruct = loaded.DataStruct;
                    fprintf('Legacy DataStruct loaded successfully.\n');
                else
                    uialert(app.UIFigure, ...
                        'No DataStructDRR / DataStruct field found in the file.', 'Information', 'Icon', 'info');
                end

                if isfield(loaded, 'RankReductionParam')
                    app.RankReductionParam = loaded.RankReductionParam;
                    fprintf('RankReductionParam loaded successfully.\n');
                else
                    fprintf('No RankReductionParam field found in this file.\n');
                end

                uialert(app.UIFigure, ...
                    sprintf('Successfully loaded: %s\nYou can now view or continue processing in the interface.', filename), ...
                    'Loading Complete', 'Icon', 'success');

                updateEventDropdown(app)
            catch ME
                uialert(app.UIFigure, ...
                    ['Error occurred while loading data: ', ME.message], ...
                    'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: RunRankReductionButton
        function RunRankReductionButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'Load data first', 'Error', 'Icon','error');
                return;
            end

            if app.rankEditField.Value <= 0
                uialert(app.UIFigure, 'rank must be int', 'Error', 'Icon','error');
                return;
            end
            
            if isempty(app.selectedEvid)
                uialert(app.UIFigure,'No events selected！','Error');
                return;
            end

            eventIDs = app.selectedEvid;  

            app.DataStructDRR = [];  
            app.RankReductionParam.flow = app.flowEditField.Value;
            app.RankReductionParam.fhigh = app.fhighEditField.Value;
    
            app.RankReductionParam.rank = app.rankEditField.Value;
            app.RankReductionParam.K = app.KEditField.Value;
            app.RankReductionParam.niter = app.niterEditField.Value;
            app.RankReductionParam.eps = app.epsEditField.Value;
            app.RankReductionParam.verb = app.verboseCheckBox.Value;
            app.RankReductionParam.mode = app.modeEditField.Value;
    
            minStationCount = 0.5 * length(app.gridStruct.stationLon); 

            h = uiprogressdlg(app.UIFigure, 'Title','Processing', 'Message','Denoising...');

            tic
            for iEvent = 1:length(eventIDs)

                h.Message = sprintf('Processing %d/%d', iEvent, length(eventIDs));
                h.Value = iEvent/length(eventIDs);
                evid = eventIDs{iEvent};
                [gather, ~] = getCommonEventGather(app.DataStruct, evid);
            
                if length(gather) < minStationCount
                    fprintf('Event %s only has %d stations (<%d). Skipped.\n', ...
                        evid, length(gather), minStationCount);
                    continue;
                end

                [gatherReconstructed, d1_otg] = rankReduction_new(gather, app.gridStruct, app.RankReductionParam);
            
                app.DataStructDRR = [app.DataStructDRR; gatherReconstructed(:)];
            
            end
            close(h)
            toc
            app.DataStructDRR = app.DataStructDRR';
            app.statusLabel.Text = 'DRR done!';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainApp.config.RankReductionParam = app.RankReductionParam;
            app.mainApp.DataStruct = app.DataStructDRR;
            app.mainApp.rankReductionStatus = 'OK';

            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.rankReductionStatus = 'Cancel';
            delete(app.UIFigure);
        end

        % Value changed function: EventSelectionDropDown
        function EventSelectionDropDownValueChanged(app, event)
            selected = app.EventSelectionDropDown.Value;
            if strcmp(selected,'All')
                
                eList = getEvents(app.DataStruct); 
                app.selectedEvid = {eList.evid};  
            else
                app.selectedEvid = {selected};
            end
            
        end

        % Button pushed function: PlotComparisonButton
        function PlotComparisonButtonPushed(app, event)
            eSelected = app.EventSelectionDropDown.Value; 
            if strcmp(eSelected,'All') || strcmp(eSelected,'(no events)')
                uialert(app.UIFigure,'Select one event','Error');
                return;
            end

            [gatherRaw, ~]   = getCommonEventGather(app.DataStruct, eSelected);
            [gatherDRR, ~]   = getCommonEventGather(app.DataStructDRR, eSelected);

            if isempty(gatherRaw) || isempty(gatherDRR)
                uialert(app.UIFigure,'cannot select events','Error');
                return;
            end

            cla(app.RawWaveformUIAxes,'reset');
            plotCommonEventGather(gatherRaw,eSelected,'trace','imagesc', app.RawWaveformUIAxes);
            title(app.RawWaveformUIAxes, ['Raw Waveforms - Event ', eSelected]);
            colormap(app.RawWaveformUIAxes, flipud(app.cmap.roma));

            cla(app.DenoisedWaveformUIAxes,'reset');
            plotCommonEventGather(gatherDRR,eSelected,'trace','imagesc',app.DenoisedWaveformUIAxes)            
            title(app.DenoisedWaveformUIAxes, ['Denoised Waveforms - Event ', eSelected]);
            colormap(app.DenoisedWaveformUIAxes, flipud(app.cmap.roma));
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            try
                if isempty(app.DataStructDRR)
                    uialert(app.UIFigure, ...
                        'No DRR results available. Please perform rank reduction processing first!',...
                        'Error', 'Icon','info');
                    return;
                end

                timestamp  = datestr(now,'yyyymmdd_HHMMSS');
                defaultName= ['DataStructDRR_', timestamp, '.mat'];


                [filename, pathname] = uiputfile(defaultName, 'Saving DRR files');
                if isequal(filename,0) || isequal(pathname,0)

                    return;
                end
                fullpath = fullfile(pathname, filename);

                DataStructDRR = app.DataStructDRR;
                RankReductionParam = app.RankReductionParam;

                % 5) saving
                % save(fullpath,'DataStructDRR','RankReductionParam','-v7.3');

                uialert(app.UIFigure, ['Successfully saved to:', fullpath], 'Done!','Icon','success');

            catch ME

                uialert(app.UIFigure, ...
                    ['Error occurred while saving data', ME.message], ...
                    'Error','Icon','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoaddataMenu = uimenu(app.FileMenu);
            app.LoaddataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddataMenuSelected, true);
            app.LoaddataMenu.Text = 'Load data';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grid Geometry')
            xlabel(app.UIAxes, 'X (km)')
            ylabel(app.UIAxes, 'Y (km)')
            zlabel(app.UIAxes, 'Z')
            app.DenoisedWaveformUIAxes = uiaxes(app.UIFigure);
            title(app.DenoisedWaveformUIAxes, 'Reconstructed Waveforms')
            xlabel(app.DenoisedWaveformUIAxes, 'X')
            ylabel(app.DenoisedWaveformUIAxes, 'Time (sec)')
            zlabel(app.DenoisedWaveformUIAxes, 'Z')
            app.RawWaveformUIAxes = uiaxes(app.UIFigure);
            title(app.RawWaveformUIAxes, 'Raw Waveforms')
            ylabel(app.RawWaveformUIAxes, 'Time (sec)')
            zlabel(app.RawWaveformUIAxes, 'Z')
            app.RankReductionPanelLabel = uilabel(app.UIFigure);
            app.RankReductionPanelLabel.Text = 'Rank Reduction Panel';
            app.processingWorkflowLabel = uilabel(app.UIFigure);
            app.processingWorkflowLabel.Text = 'Status:';
            app.statusLabel = uilabel(app.UIFigure);
            app.statusLabel.Text = '';
            app.GridParametersPanel = uipanel(app.UIFigure);
            app.GridParametersPanel.Title = 'Grid Parameters';
            app.CreateGridButton = uibutton(app.GridParametersPanel, 'push');
            app.CreateGridButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGridButtonPushed, true);
            app.CreateGridButton.Text = 'Create Grid';
            app.dxkmEditFieldLabel = uilabel(app.GridParametersPanel);
            app.dxkmEditFieldLabel.Text = 'dx (km):';
            app.dxkmEditField = uieditfield(app.GridParametersPanel, 'numeric');
            app.dxkmEditField.Value = 5;
            app.dykmEditFieldLabel = uilabel(app.GridParametersPanel);
            app.dykmEditFieldLabel.Text = 'dy (km):';
            app.dykmEditField = uieditfield(app.GridParametersPanel, 'numeric');
            app.dykmEditField.Value = 5;
            app.RankReductionParametersPanel = uipanel(app.UIFigure);
            app.RankReductionParametersPanel.Title = 'Rank Reduction Parameters';
            app.flowEditFieldLabel = uilabel(app.RankReductionParametersPanel);
            app.flowEditFieldLabel.Text = 'flow';
            app.flowEditField = uieditfield(app.RankReductionParametersPanel, 'numeric');
            app.flowEditField.Value = 0.1;
            app.fhighEditFieldLabel = uilabel(app.RankReductionParametersPanel);
            app.fhighEditFieldLabel.Text = 'fhigh';
            app.fhighEditField = uieditfield(app.RankReductionParametersPanel, 'numeric');
            app.fhighEditField.Value = 1.2;
            app.rankEditFieldLabel = uilabel(app.RankReductionParametersPanel);
            app.rankEditFieldLabel.Text = 'rank';
            app.rankEditField = uieditfield(app.RankReductionParametersPanel, 'numeric');
            app.rankEditField.Value = 5;
            app.KEditFieldLabel = uilabel(app.RankReductionParametersPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.RankReductionParametersPanel, 'numeric');
            app.KEditField.Value = 4;
            app.niterEditFieldLabel = uilabel(app.RankReductionParametersPanel);
            app.niterEditFieldLabel.Text = 'niter';
            app.niterEditField = uieditfield(app.RankReductionParametersPanel, 'numeric');
            app.niterEditField.Value = 10;
            app.epsEditFieldLabel = uilabel(app.RankReductionParametersPanel);
            app.epsEditFieldLabel.Text = 'eps';
            app.epsEditField = uieditfield(app.RankReductionParametersPanel, 'numeric');
            app.epsEditField.Value = 0.0001;
            app.tmaxEditFieldLabel = uilabel(app.RankReductionParametersPanel);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.RankReductionParametersPanel, 'numeric');
            app.tmaxEditField.Value = 50;
            app.modeEditFieldLabel = uilabel(app.RankReductionParametersPanel);
            app.modeEditFieldLabel.Text = 'mode';
            app.modeEditField = uieditfield(app.RankReductionParametersPanel, 'numeric');
            app.modeEditField.Value = 1;
            app.verboseCheckBox = uicheckbox(app.RankReductionParametersPanel);
            app.verboseCheckBox.Text = 'verbose';
            app.SelectEventDropDownLabel = uilabel(app.UIFigure);
            app.SelectEventDropDownLabel.Text = 'Select Event';
            app.EventSelectionDropDown = uidropdown(app.UIFigure);
            app.EventSelectionDropDown.Items = {'All'};
            app.EventSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EventSelectionDropDownValueChanged, true);
            app.EventSelectionDropDown.Value = 'All';
            app.RunRankReductionButton = uibutton(app.UIFigure, 'push');
            app.RunRankReductionButton.ButtonPushedFcn = createCallbackFcn(app, @RunRankReductionButtonPushed, true);
            app.RunRankReductionButton.Text = 'Run Rank Reduction';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.PlotComparisonButton = uibutton(app.UIFigure, 'push');
            app.PlotComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @PlotComparisonButtonPushed, true);
            app.PlotComparisonButton.Text = 'Plot Comparison';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RankReductionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end